classdef HEVApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SessionMenu                     matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        SimulationMenu                  matlab.ui.container.Menu
        ExportResultsMenu               matlab.ui.container.Menu
        LoadResultsMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationonHEVP4Menu        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        GridLayout37                    matlab.ui.container.GridLayout
        GridLayout38                    matlab.ui.container.GridLayout
        LoadSessionButton               matlab.ui.control.Button
        NewSessionButton                matlab.ui.control.Button
        WelcomeLabel                    matlab.ui.control.Label
        LoginImage                      matlab.ui.control.Image
        DriveCycleSetupTab              matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        GridLayout31                    matlab.ui.container.GridLayout
        GridLayout32                    matlab.ui.container.GridLayout
        DriveCycleTable                 matlab.ui.control.Table
        ClearScatterPointButton         matlab.ui.control.Button
        DriveCycleAxes                  matlab.ui.control.UIAxes
        DriveCycleOptionsPanel          matlab.ui.container.Panel
        GridLayout46                    matlab.ui.container.GridLayout
        WideOpenThrottleWOTPanel        matlab.ui.container.Panel
        GridLayout49                    matlab.ui.container.GridLayout
        SourceVelocityUnitsDropDown     matlab.ui.control.DropDown
        SourceVelocityUnitsDropDownLabel  matlab.ui.control.Label
        WOTsimulationtimet_wotendEditField  matlab.ui.control.NumericEditField
        WOTsimulationtimet_wotendEditFieldLabel  matlab.ui.control.Label
        Nominalreferencespeedxdot_wot1EditField  matlab.ui.control.NumericEditField
        Nominalreferencespeedxdot_wot1EditFieldLabel  matlab.ui.control.Label
        Finalreferencespeedxdot_wot2EditField  matlab.ui.control.NumericEditField
        Finalreferencespeedxdot_wot2EditFieldLabel  matlab.ui.control.Label
        Initialreferencespeedxdot_wotoEditField  matlab.ui.control.NumericEditField
        Initialreferencespeedxdot_wotoEditFieldLabel  matlab.ui.control.Label
        Timetostartdecelerationt_wot2sEditField  matlab.ui.control.NumericEditField
        Timetostartdecelerationt_wot2sEditFieldLabel  matlab.ui.control.Label
        Starttimet_wot1sEditField       matlab.ui.control.NumericEditField
        Starttimet_wot1sEditFieldLabel  matlab.ui.control.Label
        GridLayout47                    matlab.ui.container.GridLayout
        RepeatcyclicallyCheckBox        matlab.ui.control.CheckBox
        EndSimulationTimeEditField      matlab.ui.control.NumericEditField
        EndSimulationTimesEditFieldLabel  matlab.ui.control.Label
        GridLayout30                    matlab.ui.container.GridLayout
        DriveCycleFromFileButton        matlab.ui.control.Button
        DriveCycleFromDropDown          matlab.ui.control.DropDown
        SelectdrivecycleLabel           matlab.ui.control.Label
        ModelParametersSetupTab         matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        EnginePanel                     matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        GridLayout36                    matlab.ui.container.GridLayout
        EngineTable                     matlab.ui.control.Table
        ECMLabel                        matlab.ui.control.Label
        GridLayout35                    matlab.ui.container.GridLayout
        SelectEngineTypeDropDown        matlab.ui.control.DropDown
        SelectEngineTypeLabel           matlab.ui.control.Label
        BatteryPanel                    matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        BatteryTable                    matlab.ui.control.Table
        BatteryTypeLabel                matlab.ui.control.Label
        VehicleParametersPanel          matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout39                    matlab.ui.container.GridLayout
        InitialBatterySOCLabel          matlab.ui.control.Label
        InitialBatterySOCSlider         matlab.ui.control.Slider
        GridLayout24                    matlab.ui.container.GridLayout
        VehicleMassLabel                matlab.ui.control.Label
        UnloadedWheelRadiusLabel        matlab.ui.control.Label
        LoadedWheelRadiusLabel          matlab.ui.control.Label
        VehicleMassEditField            matlab.ui.control.NumericEditField
        UnloadedWheelRadiusEditField    matlab.ui.control.NumericEditField
        LoadedWheelRadiusEditField      matlab.ui.control.NumericEditField
        EnvironmentParametersPanel      matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        AtmosphericPressureLabel        matlab.ui.control.Label
        AtmosphericPressureSlider       matlab.ui.control.Slider
        GridLayout14                    matlab.ui.container.GridLayout
        TemperatureLabel                matlab.ui.control.Label
        LongitudinalWindSpeedLabel      matlab.ui.control.Label
        GradeLabel                      matlab.ui.control.Label
        LongitudinalWindSpeedEditField  matlab.ui.control.NumericEditField
        TemperatureSpinner              matlab.ui.control.Spinner
        GradeEditField                  matlab.ui.control.NumericEditField
        SimulationandVisualizationTab   matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        ControlPanel                    matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        GenerateReportButton            matlab.ui.control.Button
        StartSimulationButton           matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        SimulationLamp                  matlab.ui.control.Lamp
        GridLayout18                    matlab.ui.container.GridLayout
        ClearallButton                  matlab.ui.control.Button
        TPCO2MassCheckBox               matlab.ui.control.CheckBox
        TPNOxMassCheckBox               matlab.ui.control.CheckBox
        TPCOMassCheckBox                matlab.ui.control.CheckBox
        TPHCMassCheckBoxn               matlab.ui.control.CheckBox
        USFuelEconomyCheckBox           matlab.ui.control.CheckBox
        BatterySOCCheckBox              matlab.ui.control.CheckBox
        BatteryCurrentCheckBox          matlab.ui.control.CheckBox
        EngineMotorTorqueCheckBox       matlab.ui.control.CheckBox
        EngineMotorSpeedCheckBoxnn      matlab.ui.control.CheckBox
        OutputplotsLabel                matlab.ui.control.Label
        VelocityCheckBox                matlab.ui.control.CheckBox
        VisualizationPanel              matlab.ui.container.Panel
        SessionLogTab                   matlab.ui.container.Tab
        GridLayout40                    matlab.ui.container.GridLayout
        SessionhistoryPanel             matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        LogTextArea                     matlab.ui.control.TextArea
        SessiondetailsPanel             matlab.ui.container.Panel
        GridLayout43                    matlab.ui.container.GridLayout
        TimeLogLabel                    matlab.ui.control.Label
        ReportTab                       matlab.ui.container.Tab
        GridLayout48                    matlab.ui.container.GridLayout
        AxesrequiredtogeneratethereportLabel  matlab.ui.control.Label
        ReportAxes                      matlab.ui.control.UIAxes
    end

    % Copyright 2021 The MathWorks, Inc.
    
    % Properties associated with Simulation and Visualization
    properties (Access = private)
        % Storage for simulation progression percentage
        SimProgressionPercentage = 0
        % Timer object to force screen refresh
        Timer
        % Check whether simulation was aborted
        SimulationAborted
        % Simulation input object
        SimInput
        % Model simulated
        SimModel
        % Labels for results plots
        PlotLabels1 = {'Target','Engine', 'Engine'}
        PlotLabels2 = {'Actual','Motor' , 'Motor' }
        % Show filename and path of loaded past results
        PastResultsLabel matlab.ui.control.Label
        % Logical variable to determine which quantity to plot
        PlotQuantity (10,1) logical
        % Axes for simulation results
        PlotAxes (10,1) matlab.graphics.axis.Axes
        % Storage variable for past simulation results
        PastResults
        % To determine which output result is being plotted
        ActiveIndex
        % Number of plots displayed
        NActivePlots
        % Container for axes objects
        TileContainer
        % Line object for current simulation
        ResultLine (2,10) matlab.graphics.chart.primitive.Line
        % Line objects (past and difference) for comparing with previous
        % simulations
        PastLine (2,10) matlab.graphics.chart.primitive.Line
        DiffLine (2,10) matlab.graphics.chart.primitive.Line
        % Titles for plots
        PlotTitle = [...
            "Trace Velocity, Target, Actual (mph)";
            "EngSpd, MotSpd (RPM)";
            "EngTrq, MotTrq (Nm)";
            "Battery Current (A)";
            "Battery SOC";
            "US Fuel Economy MPGe";
            "TP HC Mass";
            "TP CO Mass";
            "TP NOx Mass";
            "TP CO2 Mass"]
        % Storage variable for latest simulation results
        Results
        % Context menu associated with each plot
        ContextMenu (10,1) matlab.ui.container.ContextMenu
        ShowMenu    (10,1) matlab.ui.container.Menu
        HideMenu    (10,1) matlab.ui.container.Menu
        ShowResMenu (10,1) matlab.ui.container.Menu
        ShowLatMenu (10,1) matlab.ui.container.Menu
        HideResMenu (10,1) matlab.ui.container.Menu
        ShowDifMenu (10,1) matlab.ui.container.Menu
        HideDifMenu (10,1) matlab.ui.container.Menu
        HideLatMenu (10,1) matlab.ui.container.Menu
    end
    
    % Properties associated with app
    properties (Access = private)
        % Check for an active session
        isSession = false
        % Check if the session has been saved
        isSavedSession = false
        % Check if simulation is currently running
        IsSimulating = false
        % Check if simulation was run
        WasSimulating = false
        % Check if past results were loaded
        isPastResultsLoaded = false
        % Structure containing Session data
        Data
        % Session username
        Username = "gcarniel"
        % Line for drive cycle plot
        DriveCycleLine
        % Label indicating end of simulation time
        EndSimulationLabel
        % Scatter point in drive cycle plot
        Scatter
        % Custom colors for lines
        Green    = [0.1953 0.8008 0.1953]
        DarkRed  = [0.6953 0.1328 0.1328]
        DarkBlue = [0 0 0.5]
        SkyBlue  = [0 0.7461 0.9961]
        Orange   = [0.9961 0.2695 0]
    end
    
    events
        VelocityDataChanged
        DriveCycleIDChanged
        SourceVelocityUnitsChanged
        EngineChanged
        EndSimulationTimeChanged
        RepeatCheckboxChanged
    end
    
    methods (Access = private)
        
        % Add log information in the Log tab
        function addLogMessage(app, MsgType, varargin)
            
            % Construct headline
            DateTime = datestr(datetime);
            Date     = convertCharsToStrings(date);
            Time     = convertCharsToStrings(DateTime(13:end));
            Headline = strcat("------ On ", Date, " at ", Time, ":");
            
            % Select message to display
            switch MsgType
                case 'SimulationStarted'
                    Msg = ["Simulation started with the following configuration:";
                        "";
                        "End Simulation time (s): " + app.Data.SimulationTime;
                        "";
                        "Drive cycle parameters: ";
                        "Drive cycle source:    " + app.Data.DriveCycleID;
                        "Source Velocity Units: " + app.Data.SourceVelocityUnits];
                    if strcmp(app.Data.DriveCycleID,'Wide Open Throttle (WOT)')
                        Msg = [Msg;
                            app.Starttimet_wot1sEditFieldLabel.Text + "                 " + app.Starttimet_wot1sEditField.Value;
                            app.Initialreferencespeedxdot_wotoEditFieldLabel.Text + "  " + app.Initialreferencespeedxdot_wotoEditField.Value;
                            app.Nominalreferencespeedxdot_wot1EditFieldLabel.Text + "  " + app.Nominalreferencespeedxdot_wot1EditField.Value;
                            app.Timetostartdecelerationt_wot2sEditFieldLabel.Text + " " + app.Timetostartdecelerationt_wot2sEditField.Value;
                            app.Finalreferencespeedxdot_wot2EditFieldLabel.Text + "    " + app.Finalreferencespeedxdot_wot2EditField.Value;
                            app.WOTsimulationtimet_wotendEditFieldLabel.Text + "       " + app.WOTsimulationtimet_wotendEditField.Value];
                    end
                    Msg = [Msg;
                        "";
                        %
                        "Environment parameters:";
                        "Wind Speed (m/s):           " + app.Data.WindSpeed;
                        "Grade (deg):                " + app.Data.Grade;
                        "Temperature (K):            " + app.Data.Temperature;
                        "Atmospheric Pressure (atm): " + app.Data.Pressure/101325;
                        "";
                        %
                        "Vehicle parameters:";
                        "Mass (kg):                 " + app.Data.Mass;
                        "Unloaded wheel radius (m): " + app.Data.UnloadedRadius;
                        "Loaded wheel radius (m):   " + app.Data.LoadedRadius;
                        "Initial Battery SOC (%):   " + app.Data.BatterySOC;
                        "Engine Type:               " + app.Data.Engine];
                case 'SimulationCompleted'
                    Msg = "Simulation completed successfully.";
                case 'SimulationNotCompleted'
                    Msg = "Simulation aborted.";
                case 'SimulationTerminated'
                    Msg = "Simulation terminated before completion.";
                case 'NewSession'
                    Msg = "New session started.";
                case 'SavedSession'
                    Msg = "Session saved successfully.";
                case 'ReportGenerated'
                    Msg = "Simulation report created successfully.";
                case 'ReportFailed'
                    Msg = "Failed attempt to create simulation report.";
                case 'SavedAsSession'
                    Msg = "Session saved at: " + convertCharsToStrings(varargin{1});
                case 'LoadedSession'
                    Msg = "Session loaded from: " + convertCharsToStrings(varargin{1});
                case 'ExportedResults'
                    Msg = "Simulation results exported to: " + convertCharsToStrings(varargin{1});
                case 'LoadedResults'
                    Msg = "Past results loaded from: " + convertCharsToStrings(varargin{1});
            end
            if strcmp(app.LogTextArea.Value,"")
                % If log text area is empty
                app.LogTextArea.Value = [Headline; Msg; ""];
            else
                app.LogTextArea.Value = [app.LogTextArea.Value; Headline; Msg; ""];
            end
        end
        
        function Answer = askToSaveCurrentSession(app)
            
            % Ask to save current session
            Answer = uiconfirm(app.UIFigure,...
                'Save changes to current session?',...
                'Save Session', 'Options', {'Yes','No','Cancel'},...
                'DefaultOption', 1, 'CancelOption', 3);
            
            % If yes, save session
            if strcmp(Answer,'Yes')
                if app.isSavedSession
                    saveCurrentSession(app)
                else
                    saveNewSession(app)
                end
            end
        end
        
        % Creates a new session: clears old session (if present), loads
        % default drive cycle, restores default Data structure, restores
        % default layout options
        function createNewSession(app)
            
            % Perform new/old session procedures, depending if there's
            % already an active session
            if ~app.isSession
                % If no session is active
                newSessionProcedures(app)
            else
                Answer = askToSaveCurrentSession(app);
                if strcmp(Answer,'Cancel')
                    return
                end
                oldSessionProcedures(app)
            end
            
            % New session is not saved
            app.isSavedSession = false;
            
            % Load default drive cycle
            app.Data.DriveCycleID = 'FTP75';
            app.Data.RepeatCyclicallyCheckbox = false;
            notify(app,'DriveCycleIDChanged')
            loadFTP75DriveCycle(app)
            
            % Initialize Data Structure
            % Default environment parameters
            app.Data.Temperature = 300;    % K
            app.Data.Pressure    = 101325; % Pa
            app.Data.Grade       = 0;      % degrees
            app.Data.WindSpeed   = 0;      % m/s
            % Default vehicle parameters
            app.Data.BatterySOC     = 3.18/5.3 * 100; % percentage
            app.Data.Mass           = 1623; % kg
            app.Data.UnloadedRadius = 0.336; % m
            app.Data.LoadedRadius   = 0.327; % m
            app.Data.Engine         = 'SiMappedEngine';
            notify(app,'EngineChanged')
            % Default app parameters
            app.Data.SourceVelocityUnits = 'm/s';
            notify(app,'SourceVelocityUnitsChanged')
            
            % Default configuration for UI objects
            app.ExportResultsMenu.Enable     = false;
            app.DriveCycleFromDropDown.Value = 'FTP75';
            app.TemperatureSpinner.Value             = app.Data.Temperature;
            app.GradeEditField.Value                 = app.Data.Grade;
            app.LongitudinalWindSpeedEditField.Value = app.Data.WindSpeed;
            app.InitialBatterySOCSlider.Value        = app.Data.BatterySOC;
            app.LoadedWheelRadiusEditField.Value     = app.Data.LoadedRadius;
            app.UnloadedWheelRadiusEditField.Value   = app.Data.UnloadedRadius;
            app.VehicleMassEditField.Value           = app.Data.Mass;
            app.SelectEngineTypeDropDown.Value       = 'SiMappedEngine';
            initializeBatteryTable(app)
            
            % Add log information
            addLogMessage(app,'NewSession');
        end
        
        % Create line objects for tiles in Simulation and Visualization tab
        function createTileLines(app,i,Res)
            
            % Distinguish between quantities with two lines (i <= 3) and one
            % line (i > 3)
            if i > 3
                % Latest results
                if strcmp(Res,'New')
                    app.ResultLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'Color','r','linewidth',2,'HandleVisibility','off');
                    app.ResultLine(1,i).DisplayName = 'Latest';
                elseif strcmp(Res,'Old')
                    app.ResultLine(1,i) = plot(app.PlotAxes(i),app.Results.Time{i},...
                        app.Results.Data{i},'r','linewidth',2);
                    app.ResultLine(1,i).DisplayName = 'Latest';
                end
                % Past results
                app.PastLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'Color',app.DarkRed,'linewidth',2,'HandleVisibility','off');
                app.PastLine(1,i).DisplayName = 'Past';
                % Difference in results
                app.DiffLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'Color',app.Orange,'linestyle',':','linewidth',2,'HandleVisibility','off');
                app.DiffLine(1,i).DisplayName = 'Difference';
            else
                % Latest results
                switch Res
                    case 'New'
                        app.ResultLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'r','linewidth',2,'HandleVisibility','off');
                        app.ResultLine(2,i) = plot(app.PlotAxes(i),NaN,NaN,'b','linewidth',2,'HandleVisibility','off');
                    case 'Old'
                        app.ResultLine(1,i) = plot(app.PlotAxes(i),app.Results.Time{i},...
                            app.Results.Data{i}(1,:),'r','linewidth',2);
                        app.ResultLine(2,i) = plot(app.PlotAxes(i),app.Results.Time{i},...
                            app.Results.Data{i}(2,:),'b','linewidth',2);
                end
                app.ResultLine(1,i).DisplayName = [app.PlotLabels1{i} ' (latest)'];
                app.ResultLine(2,i).DisplayName = [app.PlotLabels2{i} ' (latest)'];
                % Past results
                app.PastLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'color',app.DarkRed,'linewidth',2,'HandleVisibility','off');
                app.PastLine(2,i) = plot(app.PlotAxes(i),NaN,NaN,'color',app.DarkBlue,'linewidth',2,'HandleVisibility','off');
                app.PastLine(1,i).DisplayName = [app.PlotLabels1{i} ' (past)'];
                app.PastLine(2,i).DisplayName = [app.PlotLabels2{i} ' (past)'];
                % Difference in results
                app.DiffLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'color',app.Orange,'linestyle',':','linewidth',2,'HandleVisibility','off');
                app.DiffLine(2,i) = plot(app.PlotAxes(i),NaN,NaN,'color',app.SkyBlue,'linestyle',':','linewidth',2,'HandleVisibility','off');
                app.DiffLine(1,i).DisplayName = [app.PlotLabels1{i} ' (difference)'];
                app.DiffLine(2,i).DisplayName = [app.PlotLabels2{i} ' (difference)'];
            end
        end
                
        function generateWOTCurve(app)
            
            % Initialize time and velocity arrays
            app.Data.Time     = transpose(0:0.1:app.WOTsimulationtimet_wotendEditField.Value);
            app.Data.Velocity = zeros(size(app.Data.Time));
            % Calculate and set velocity
            iRise = round(app.Starttimet_wot1sEditField.Value/0.1) + 2;
            iSet  = round(app.Timetostartdecelerationt_wot2sEditField.Value/0.1) + 2;
            app.Data.Velocity(1:iRise-1)  = app.Initialreferencespeedxdot_wotoEditField.Value;
            app.Data.Velocity(iRise:iSet) = app.Nominalreferencespeedxdot_wot1EditField.Value;
            app.Data.Velocity(iSet:end)   = app.Finalreferencespeedxdot_wot2EditField.Value;
            % Set time
            app.Data.SimulationTime = app.Data.Time(end);
            
            notify(app,'EndSimulationTimeChanged')
            notify(app,'VelocityDataChanged')
        end
        
        % Context menu item: hides the selected lines from the selected
        % plot
        function hideLineMenuSelected(app, source, ~)
            
            i = str2double(source.Parent.Parent.Tag);
            switch source.Text
                case 'Latest Results'
                    app.ShowLatMenu(i).Checked = 'off';
                    app.HideLatMenu(i).Checked = 'off';
                    l = app.ResultLine;
                case 'Past Results'
                    app.ShowResMenu(i).Checked = 'off';
                    app.HideResMenu(i).Checked = 'off';
                    l = app.PastLine;
                case 'Difference'
                    app.ShowDifMenu(i).Checked = 'off';
                    app.HideDifMenu(i).Checked = 'off';
                    l = app.DiffLine;
            end
            
            if i > 3
                set(l(1,i),'XData',NaN,'YData',NaN,'HandleVisibility','off');
            else
                % Velocity, engine speed and engine torque have
                % two lines
                set(l(:,i),'XData',NaN,'YData',NaN,'HandleVisibility','off');
            end
            
        end
        
        % Initialize table containing information about battery
        function initializeBatteryTable(app)
            
            % Load battery parameters
            s = load('Battery_param.mat');
            
            % Get parameters needed for table valus
            BattCapInit    = app.Data.BatterySOC*s.('BattChargeMax')/100;
            app.Data.Vinit = s.('Ns')*interp1(s.('CapLUTBp'),s.('Em'),BattCapInit/s.('BattChargeMax'));
            
            % Name-value pairs to show in the table
            Pairs = {...
                'Battery efficiency [%]', s.('eff');
                'Rated capacity at nominal temperature, BattChargeMax [Ah]', s.('BattChargeMax');
                'Open circuit voltage table data, Em [V]:', s.('Em');
                'Open circuit voltage breakpoints 1, CapLUTBp []:', s.('CapLUTBp');
                'Internal resistance table data, RInt [Ohms]:', s.('RInt');
                'Battery temperature breakpoints 1, BattTempBp [K]:', s.('BattTempBp');
                'Battery Capacity breakpoints 2, CapSOCBp []:', s.('CapSOCBp');
                'Number of cells in series, Ns []:', s.('Ns');
                'Number of cells in parallel, Np []:', s.('Np');
                'Initial battery capacity, BattCapInit [Ah]:', BattCapInit;
                'Output battery voltage time constant, Tc [s]:', s.('Tc');
                'Output battery voltage initial value, Vinit [V]:', app.Data.Vinit;
                };
            
            % Update battery table
            for i = 1:size(Pairs,1)
                if isscalar(Pairs{i,2})
                    Pairs{i,2} = num2str(Pairs{i,2});
                else
                    dim = size(Pairs{i,2});
                    Pairs{i,2} = char("Array of size " + dim(1) + "x" + dim(2));
                end
            end
            % Update battery table
            app.BatteryTable.Data = Pairs;
        end

        % Check that the imported drive cycle meets the required
        % specifications
        function IsValid = isValidDriveCycle(app,file)
            
            % Assume true first. Attempt to negate assumption
            IsValid = true;
            
            % Check that a file has been selected
            if isnumeric(file)
                IsValid = false;
                return
            end
            
            % Depending on the extension (mat, xlsx, xls, txt) do specific
            % checks
            [~, FileName, FileExtension] = fileparts(file);
            switch FileExtension
                case '.mat'
                    MsgTitle = 'MAT File Conditions';
                    s        = load(file);
                    VarName  = fields(s);
                    
                    % Check: variable name must match the file name
                    IsValid = validateStatement(app,strcmp(VarName{1},FileName),...
                        'At least one variable name in MAT file must match the file name.',MsgTitle);
                    if ~IsValid, return, end
                    
                    Array = s.(VarName{1});
                    % Check: variable must be double or timeseries
                    IsValid = validateStatement(app,isa(Array,'double') || isa(Array,'timeseries'),...
                        'Variable must be either of type double or timeseries.',MsgTitle);
                    if ~IsValid, return, end
                    
                    % Check: if double, must be 2 columns with first column sorted
                    if isa(Array,'double')
                        ArraySize = size(Array);
                        
                        % Check that variable has two columns
                        IsValid = validateStatement(app,isequal(ArraySize(2),2),...
                            'Variable must be an Nx2 array.',MsgTitle);
                        if ~IsValid, return, end
                        
                        % Check that time column is sorted
                        IsValid = validateStatement(app,issorted(Array(:,1)),...
                            'Time must be sorted in ascending order.',MsgTitle);
                        if ~IsValid, return, end
                    end
                case {'.xlsx' '.xls'}
                    MsgTitle = 'Excel File Conditions';
                    Tab      = readtable(file);
                    VarName  = Tab.Properties.VariableNames;
                    
                    % Check: time column is sorted
                    IsValid = validateStatement(app,issorted(Tab.(VarName{1})),...
                        'Time must be sorted in ascending order.',MsgTitle);
                    if ~IsValid, return, end
                case '.txt'
                    MsgTitle = 'Text File Conditions';
                    Tab      = readtable(file);
                    VarName  = Tab.Properties.VariableNames;
                    
                    % Check: variables in Text file must have a header
                    IsValid = validateStatement(app,~strcmp(VarName{1},'Var1'),...
                        'Variables in Excel file must have a header.',MsgTitle);
                    if ~IsValid, return, end
                    
                    % Check: time column is sorted
                    IsValid = validateStatement(app,issorted(Tab.(VarName{1})),...
                        'Time must be sorted in ascending order.',MsgTitle);
                    if ~IsValid, return, end
            end
        end
        
        % Check that the loaded results file is valid
        function IsValid = isValidResultsFile(app,file)
            
            IsValid = true; %#ok<NASGU>
            
            % Check that a file has been selected
            if isnumeric(file)
                IsValid = false;
                return
            end
            
            % File must be of type mat and must contain an array called
            % Results
            [~,~,FileExtension] = fileparts(file);
            switch FileExtension
                case '.mat'
                    MsgTitle = 'MAT File Conditions';
                    s        = load(file);
                    VarName  = fields(s);
                    
                    % File must have time
                    IsValid = validateStatement(app,strcmp(VarName{1},'Time'),...
                        'File does not contain Time.',MsgTitle);
                    if ~IsValid, return, end
                    % File must have data
                    IsValid = validateStatement(app,strcmp(VarName{2},'Data'),...
                        'File does not contain Data.',MsgTitle);
                    if ~IsValid, return, end
                case '.xlsx'
                    MsgTitle = 'Excel File Conditions';
                    try
                        s = readtable(file,'PreserveVariableNames',true);
                    catch
                        IsValid  = validateStatement(app,false,...
                            'Invalid Excel file.',MsgTitle);
                        return
                    end
                    % Table must have 14 columns
                    IsValid = validateStatement(app,isequal(size(s,2),14),...
                        'Invalid Excel file.',MsgTitle);
                otherwise
                    MsgTitle = 'Unsupported File Type';
                    IsValid  = validateStatement(app,false,...
                        'File must be of type MAT or Excel.',MsgTitle);
            end
        end
        
        % Check if the file loaded contains a valid session
        function Out = isValidSession(~,file)
            
            % Check if the session is valid. First assume true
            Out = true;
            
            % Try to falsify statement:
            % Check that a file has been selected
            if isnumeric(file)
                Out = false;
                return
            end
        end
        
        % Callback function activated when Drive Cycle line is clicked on.
        % It reconfigures the line according to the movement of the mouse.
        function lineSelectedCallback(app,~,~)
            
            if strcmp(app.Data.DriveCycleID,'Wide Open Throttle (WOT)')
                % Margin of error within which vertical line can be selected
                margin = 0.3;
                % Find which point on the axes was clicked
                SelectedTime = app.DriveCycleAxes.CurrentPoint;
                % Determine which part of the plot is to be modified. dir =
                % 1/2 (x- and y- directions). par = parameter to be changed
                if SelectedTime(1,1) <= app.Starttimet_wot1sEditField.Value - margin
                    dir = 2;
                    par = app.Initialreferencespeedxdot_wotoEditField;
                    id  = 1;
                elseif SelectedTime(1,1) <= app.Starttimet_wot1sEditField.Value + 0.1 + margin
                    dir = 1;
                    par = app.Starttimet_wot1sEditField;
                    id  = 2;
                elseif SelectedTime(1,1) <= app.Timetostartdecelerationt_wot2sEditField.Value - margin
                    dir = 2;
                    par = app.Nominalreferencespeedxdot_wot1EditField;
                    id = 3;
                elseif SelectedTime(1,1) <= app.Timetostartdecelerationt_wot2sEditField.Value + 0.1 + margin
                    dir = 1;
                    par = app.Timetostartdecelerationt_wot2sEditField;
                    id = 4;
                else
                    dir = 2;
                    par = app.Finalreferencespeedxdot_wot2EditField;
                    id = 5;
                end
                % Change pointer according to selected segment
                PointerType = {'left','top'};
                app.UIFigure.Pointer = PointerType{dir};
                % Create callbacks
                app.UIFigure.WindowButtonMotionFcn = @moveDriveCycleLine;
                app.UIFigure.WindowButtonUpFcn = @restoreDef;
            end
            % Move line according to movement of the mouse
            function moveDriveCycleLine(~,~)
                
                SelTime   = app.DriveCycleAxes.CurrentPoint;
                par.Value = max(0,round(SelTime(1,dir),1));
                % Edge cases: acceleration time must not exceed deceleration
                % time (case 2), and deceleration time must not exceed
                % final time (case 4)
                switch id
                    case 1
                        app.Data.Initialreferencespeedxdot_woto = par.Value;
                    case 2
                        par.Value = min(app.Data.Timetostartdecelerationt_wot2s-0.1,par.Value);
                        app.Data.Starttimet_wot1s = par.Value;
                    case 3
                        app.Data.Nominalreferencespeedxdot_wot1 = par.Value;
                    case 4
                        par.Value = max(app.Data.Starttimet_wot1s+0.1,min(app.Data.WOTsimulationtimet_wotend-0.1,par.Value));
                        app.Data.Timetostartdecelerationt_wot2s = par.Value;
                    case 5
                        app.Data.Finalreferencespeedxdot_wot2 = par.Value;
                end
                generateWOTCurve(app);
            end
            % Remove callbacks when user releases button
            function restoreDef(~,~)
                
                app.UIFigure.Pointer = 'arrow';
                app.UIFigure.WindowButtonMotionFcn = [];
                app.UIFigure.WindowButtonUpFcn     = [];
            end
        end
        
        % Load FTP75 Drive cycle and update drive cycle plot
        function loadFTP75DriveCycle(app)
            
            s = load('cycleFTP75.mat');
            app.Data.Time           = s.cycleFTP75.Time;
            app.Data.Velocity       = s.cycleFTP75.Data;
            app.Data.SimulationTime = app.Data.Time(end);
            
            notify(app,'VelocityDataChanged');
            notify(app,'EndSimulationTimeChanged')
        end
        
        % Load session from file
        function loadSession(app)
            
            % If there's an active session, ask user to save current session
            if app.isSession
                Answer = askToSaveCurrentSession(app);
                if strcmp(Answer,'Cancel')
                    return
                end
            end
            
            % Select session file
            [file, path] = uigetfile('*.mat');
            
            if isValidSession(app, [path file])
                % Load session
                app.Data.SessionID = [path file];
                SessionData        = load(app.Data.SessionID);
                app.Data           = SessionData.Data;
                notify(app,'VelocityDataChanged')
                notify(app,'DriveCycleIDChanged')
                notify(app,'EngineChanged')
                notify(app,'EndSimulationTimeChanged')
                
                % Update app layout
                app.DriveCycleFromDropDown.Value         = app.DriveCycleFromDropDown.Items{1};
                app.InitialBatterySOCSlider.Value        = app.Data.BatterySOC;
                app.LongitudinalWindSpeedEditField.Value = app.Data.WindSpeed;
                app.GradeEditField.Value                 = app.Data.Grade;
                app.TemperatureSpinner.Value             = app.Data.Temperature;
                app.AtmosphericPressureSlider.Value      = app.Data.Pressure/101325;
                app.LoadedWheelRadiusEditField.Value     = app.Data.LoadedRadius;
                app.UnloadedWheelRadiusEditField.Value   = app.Data.UnloadedRadius;
                app.VehicleMassEditField.Value           = app.Data.Mass;
                app.SelectEngineTypeDropDown.Value       = app.Data.Engine;
                app.EndSimulationTimeEditField.Value     = app.Data.SimulationTime;
                app.RepeatcyclicallyCheckBox.Value       = app.Data.RepeatCyclicallyCheckbox;
                initializeBatteryTable(app);
                if strcmp(app.Data.DriveCycleID,'Wide Open Throttle (WOT)')
                    app.Starttimet_wot1sEditField.Value               = app.Data.Starttimet_wot1s;
                    app.Initialreferencespeedxdot_wotoEditField.Value = app.Data.Initialreferencespeedxdot_woto;
                    app.Nominalreferencespeedxdot_wot1EditField.Value = app.Data.Nominalreferencespeedxdot_wot1;
                    app.Timetostartdecelerationt_wot2sEditField.Value = app.Data.Timetostartdecelerationt_wot2s;
                    app.Finalreferencespeedxdot_wot2EditField.Value   = app.Data.Finalreferencespeedxdot_wot2;
                    app.WOTsimulationtimet_wotendEditField.Value      = app.Data.WOTsimulationtimet_wotend;
                end
                
                % Log data
                addLogMessage(app,'LoadedSession',[path, file]);
                
                % Perform new/old session procedures, depending if there's
                % an active session
                if ~app.isSession
                    newSessionProcedures(app)
                else
                    oldSessionProcedures(app)
                end
                app.isSavedSession = true;
            end
        end
        
        % Procedures when new session is created
        function newSessionProcedures(app)
            
            app.DriveCycleSetupTab.Parent            = app.TabGroup;
            app.ModelParametersSetupTab.Parent       = app.TabGroup;
            app.SimulationandVisualizationTab.Parent = app.TabGroup;
            app.SessionLogTab.Parent                 = app.TabGroup;
            app.SaveMenu.Visible                     = true;
            app.SaveAsMenu.Visible                   = true;
            app.SimulationMenu.Visible               = true;
            app.TabGroup.SelectedTab                 = app.DriveCycleSetupTab;
            app.isSession                            = true;
        end
        
        % Procedures when a new session overtakes an active one
        function oldSessionProcedures(app)
            
            % Remove old scatter points, if any
            ClearScatterPointButtonPushed(app,[]);
            app.WasSimulating                    = false;
            
            % Remove loaded past results
            delete(app.PastResultsLabel)
            app.GridLayout18.RowHeight = {'1x', 'fit'};
            app.PastResultsLabel = matlab.ui.control.Label.empty;
            app.isPastResultsLoaded = false;
            
            % Clear all plots
            ClearallButtonPushed(app)
            
            % Disable report generation button
            app.GenerateReportButton.Enable = false;
            
            % Disable menu options
            for i = 1:10
                app.ShowResMenu(i).Checked = false;
                app.ShowResMenu(i).Enable  = false;
                app.ShowLatMenu(i).Checked = false;
                app.ShowLatMenu(i).Enable  = false;
                app.ShowDifMenu(i).Checked = false;
                app.ShowDifMenu(i).Enable  = false;
                app.HideResMenu(i).Checked = false;
                app.HideResMenu(i).Enable  = false;
                app.HideLatMenu(i).Checked = false;
                app.HideLatMenu(i).Enable  = false;
                app.HideDifMenu(i).Checked = false;
                app.HideDifMenu(i).Enable  = false;
            end
        end
        
        % Listener callback: set appropriate drive cycle options, update
        % panel title and drive cycle plot title
        function onDriveCycleIDChanged(app,~,~)
            
            switch app.Data.DriveCycleID
                case 'FTP75'
                    %setDefaultDriveCycleOptions(app)
                    app.DriveCycleOptionsPanel.Title = ['Drive cycle options - ' app.Data.DriveCycleID];
                    app.DriveCycleAxes.Title.String  = ['Drive cycle - ' app.Data.DriveCycleID];
                    app.WideOpenThrottleWOTPanel.Parent = [];
                case 'Wide Open Throttle (WOT)'
                    %setWOTDriveCycleOptions(app)
                    app.WideOpenThrottleWOTPanel.Parent = app.GridLayout46;
                    app.DriveCycleOptionsPanel.Title = ['Drive cycle options - ' app.Data.DriveCycleID];
                    app.DriveCycleAxes.Title.String  = ['Drive cycle - ' app.Data.DriveCycleID];
                    % Update data structure
                    app.Data.Starttimet_wot1s               = app.Starttimet_wot1sEditField.Value;
                    app.Data.Initialreferencespeedxdot_woto = app.Initialreferencespeedxdot_wotoEditField.Value;
                    app.Data.Nominalreferencespeedxdot_wot1 = app.Nominalreferencespeedxdot_wot1EditField.Value;
                    app.Data.Timetostartdecelerationt_wot2s = app.Timetostartdecelerationt_wot2sEditField.Value;
                    app.Data.Finalreferencespeedxdot_wot2   = app.Finalreferencespeedxdot_wot2EditField.Value;
                    app.Data.WOTsimulationtimet_wotend      = app.WOTsimulationtimet_wotendEditField.Value;
                otherwise
                    setExtendedDriveCycleOptions(app)
                    app.DriveCycleOptionsPanel.Title = 'Drive cycle options';
                    app.DriveCycleAxes.Title.String  = 'Drive cycle';
                    app.WideOpenThrottleWOTPanel.Parent = [];
            end
            % Clear scatter points, if present
            ClearScatterPointButtonPushed(app,[]);
        end
        
        % Listener callback: update engine table and ECM Controller
        function onEngineChanged(app,~,~)
            
            % Define name-pair value arguments for engine table
            switch app.Data.Engine
                case 'SiEngine'
                    s = load('SiEngine_param.mat');
                    Pairs = {...
                        'Number of cylinders, NCyl []:', s.('NCyl');
                        'Crank revolutions per power stroke, Cps []:', s.('Cps');
                        'Total displaced volume, Vd [m^3]:', s.('Vd');
                        'Ideal gas constant air, Rair [J/(Kg*K)]', s.('Rair');
                        'Air standard pressure, Pstd [Pa]:', s.('Pstd');
                        'Air standard temperature, Tstd [K]:', s.('Tstd');
                        'Cylinder volume at intake valve close table, f_vivc [L]:', s.('f_vivc');
                        'Cylinder volume intake cam phase breakpoints, f_vivc_icp_bpt [degCrkAdv]', s.('f_vivc_icp_bpt');
                        'Cylinder trapped mass correction factor, f_tm_corr []:', s.('f_tm_corr');
                        'Normalized density breakpoints, f_tm_corr_nd_bpt', s.('f_tm_corr_nd_bpt');
                        'Engine speed breakpoints, f_tm_corr_n_bpt [rpm]', s.('f_tm_corr_n_bpt');
                        'Intake mass flow, f_mdot_intk [g/s]:', s.('f_mdot_intk');
                        'Exhaust cam phase breakpoints, f_mdot_intk_ecp_bpt [degCrkRet]', s.('f_mdot_intk_ecp_bpt');
                        'Trapped mass flow breakpoints, f_mdot_trpd_bpt [g/s]:', s.('f_mdot_trpd_bpt');
                        'Air mass flow correction factor, f_mdot_air_corr []:', s.('f_mdot_air_corr');
                        'Engine load breakpoints for air mass flow correction, f_mdot_air_corr_ld_bpt []:', s.('f_mdot_air_corr_ld_bpt');
                        'Engine speed breakpoints for air mass flow correction, f_mdot_air_n_bpt [RPM]:', s.('f_mdot_air_n_bpt');
                        };
                case 'SiMappedEngine'
                    s = load('SiMappedEngine_param.mat');
                    Pairs = {...
                        'Breakpoints for commanded torque input, f_tbrake_t_bpt [Nm]:', s.('f_tbrake_t_bpt');
                        'Breakpoints for engine speed input, f_tbrake_n_bpt [rpm]:', s.('f_tbrake_n_bpt');
                        'Number of cylinders, NCyl []:', s.('NCyl');
                        'Crank revolutions per power stroke, Cps []:', s.('Cps');
                        'Total displaced volume, Vd [m^3]:', s.('Vd');
                        'Fuel lower heating value, Lhv [J/kg]:',46e6;
                        'Fuel specific gravity, Sg []:', 0.745;
                        'Ideal gas constant air, Rair [J/(Kg*K)]', s.('Rair');
                        'Air standard pressure, Pstd [Pa]:', s.('Pstd');
                        'Air standard temperature, Tstd [K]:', s.('Tstd');
                        };
            end
            
            for i = 1:size(Pairs,1)
                if isscalar(Pairs{i,2})
                    Pairs{i,2} = num2str(Pairs{i,2});
                else
                    dim = size(Pairs{i,2});
                    Pairs{i,2} = char("Array of size " + dim(1) + "x" + dim(2));
                end
            end
            % Update engine table
            app.EngineTable.Data = Pairs;
            
            % Update ECM Controller label:
            switch app.Data.Engine
                case {'SiEngine', 'SiDLEngine','SiMappedEngine'}
                    app.ECMLabel.Text = ...
                        'Engine Controller Module (ECM): SiEngineController';
                case {'CiEngine' 'CiMappedEngine'}
                    app.ECMLabel.Text = ...
                        'Engine Controller Module (ECM): CiEngineController';
            end
        end
                
        % Listener callback: update end simulation time, horizontal limits of plots in
        % Simulation and Visualization tab and label in drive cycle plot
        function onEndSimulationTimeChanged(app,~,~)
            
            app.EndSimulationTimeEditField.Value = app.Data.SimulationTime;
            app.EndSimulationLabel.String = ['Simulation end time: '...
                num2str(app.EndSimulationTimeEditField.Value) ' s'];
            app.RepeatcyclicallyCheckBox.Value = app.Data.SimulationTime > app.Data.Time(end);
            app.Data.RepeatCyclicallyCheckbox = app.RepeatcyclicallyCheckBox.Value;
        end
        
        % Listener callback: update drive cycle table and plot units
        function onSourceVelocityUnitsChanged(app,~,~)
            
            if ~strcmp(app.Data.SourceVelocityUnits,'Select...')
                app.DriveCycleTable.ColumnName{2} = ['Velocity (' app.Data.SourceVelocityUnits ')'];
                app.DriveCycleAxes.YLabel.String  = ['Velocity (' app.Data.SourceVelocityUnits ')'];
            else
                app.DriveCycleTable.ColumnName{2} = 'Velocity (No Units)';
                app.DriveCycleAxes.YLabel.String  = 'Velocity (No Units)';
            end
        end
        
        % Listener callback: update drive cycle plot
        function onVelocityDataChanged(app,~,~)
            
            % Update drive cycle curve
            app.DriveCycleTable.Data = [app.Data.Time app.Data.Velocity];
            app.DriveCycleLine.XData = app.Data.Time;
            app.DriveCycleLine.YData = app.Data.Velocity;
            
            if strcmp(app.Data.DriveCycleID,'Wide Open Throttle (WOT)')
                app.DriveCycleAxes.YLim = [-3 5+...
                    max([app.Initialreferencespeedxdot_wotoEditField.Value...
                    app.Nominalreferencespeedxdot_wot1EditField.Value...
                    app.Finalreferencespeedxdot_wot2EditField.Value])];
            else
                app.DriveCycleAxes.YLim = [min(app.Data.Velocity)-2 max(app.Data.Velocity)+5];
            end
            
            % Update position of "End simulation" label
            y = app.DriveCycleAxes.YLim;
            x = app.DriveCycleAxes.XLim;
            x = x(1) + 0.95*diff(x);
            y = y(1) + 0.95*diff(y);
            app.EndSimulationLabel.Position = [x y];
        end
        
        function plotLatestSimulationResults(app,i)
            
            if i > 3
                app.ResultLine(1,i).XData = app.Results.Time;
                app.ResultLine(1,i).YData = app.Results.Data{i};
                app.ResultLine(1,i).HandleVisibility = 'on';
            else
                app.ResultLine(1,i).XData = app.Results.Time;
                app.ResultLine(1,i).YData = app.Results.Data{i}(:,1);
                app.ResultLine(2,i).XData = app.Results.Time;
                app.ResultLine(2,i).YData = app.Results.Data{i}(:,2);
                app.ResultLine(1,i).HandleVisibility = 'on';
                app.ResultLine(2,i).HandleVisibility = 'on';
            end
            
            app.ShowLatMenu(i).Checked = true;
            app.HideLatMenu(i).Checked = true;
        end
                
        function processOutputs(app, opIdx, time, data)
            
            app.Results.Time{opIdx} = [app.Results.Time{opIdx} time];
            app.Results.Data{opIdx} = [app.Results.Data{opIdx} data];
            app.SimProgressionPercentage = ceil(time/app.Data.SimulationTime*100);
        end
        
        % Run procedures when simulation is completed
        function runEndSimulationProcedures(app)
            
            % Restore simulation lamp default mode
            setSimulationLamp(app,app.Green,'Ready to simulate')
            
            for i = 1:10
                app.ShowLatMenu(i).Enable = true;
                app.HideLatMenu(i).Enable = true;
            end
            
            % Enable / disable components
            app.EndSimulationTimeEditField.Editable = true;
            app.ExportResultsMenu.Enable            = true;
            app.GenerateReportButton.Enable         = true;
            
            % Enable diff context menu if past results are loaded
            if app.isPastResultsLoaded
                for i = 1:10
                    app.ShowDifMenu(i).Enable = true;
                    app.HideDifMenu(i).Enable = true;
                end
            end
            
            % Add log information about the simulation
            addLogMessage(app,'SimulationCompleted');
        end
        
        % Save current session
        function saveCurrentSession(app)
            
            Data = app.Data; %#ok<ADPROP>
            save(app.Data.SessionID,'Data')
            app.isSavedSession = true;
        end
        
        % Save new session
        function saveNewSession(app)
            
            [filename, path] = uiputfile('*.mat',[],'NewSession.mat');
            if ~isnumeric([path, filename])
                app.Data.SessionID = [path filename];
                saveCurrentSession(app)
                addLogMessage(app,'SavedAsSession',[path, filename]);
            else
                % User selected "Cancel" and did not specify a path
                return
            end
        end
        
        % Set drive cycle options to extended mode (for custom drive
        % cycles): requires specifying the source velocity units
        function setExtendedDriveCycleOptions(app)
            
            app.GridLayout47.RowHeight = {'fit', 'fit'};
            
            % Create SourceVelocityUnitsDropDown
            app.SourceVelocityUnitsDropDown                 = uidropdown(app.GridLayout47);
            app.SourceVelocityUnitsDropDown.Items           = {'Select...', 'm/s', 'km/h', 'mph'};
            app.SourceVelocityUnitsDropDown.Layout.Row      = 2;
            app.SourceVelocityUnitsDropDown.BackgroundColor = [1 1 1];
            app.SourceVelocityUnitsDropDown.Layout.Column   = 2;
            app.SourceVelocityUnitsDropDown.Value           = 'Select...';
            app.SourceVelocityUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @sourceVelocityUnitsDropDownValueChanged, true);
            
            % Create SourceVelocityUnitsDropDownLabel
            app.SourceVelocityUnitsDropDownLabel               = uilabel(app.GridLayout47);
            app.SourceVelocityUnitsDropDownLabel.Layout.Row    = 2;
            app.SourceVelocityUnitsDropDownLabel.Layout.Column = 1;
            app.SourceVelocityUnitsDropDownLabel.Text          = 'Source velocity Units:';
        end
        
        % Assigns model parameters to Simulink model before starting the
        % simulation
        function setModelParametersToSimulinkModel(app)
            
            % Select which model to simulate. The model name needs to be
            % passed explicitly to the simulation input object. Each case
            % selects the same model with a different variant selected (the
            % vehicle engine)
            switch app.Data.Engine
                case 'SiEngine'
                    app.SimModel = 'Hev_SiEngine';
                    app.SimInput = Simulink.SimulationInput('Hev_SiEngine');
                case 'SiMappedEngine'
                    app.SimModel = 'Hev_SiMappedEngine';
                    app.SimInput = Simulink.SimulationInput('Hev_SiMappedEngine');
            end
            
            % Specify the simulation callbacks
            app.SimInput = simulink.compiler.setExternalOutputsFcn(app.SimInput, @app.processOutputs);
            %app.SimInput = simulink.compiler.setPostStepFcn(app.SimInput, @app.postStepFcn);
            
            % If repetability checkbox is on, then repeat drive cycle cyclically
            if app.Data.SimulationTime > app.Data.Time(end)
                dum       = floor(app.Data.SimulationTime/app.Data.Time(end));
                [~, dum1] = min(abs(app.Data.Time-rem(app.Data.SimulationTime,app.Data.Time(end))));
                Time0     = app.Data.Time;
                for i = 1:dum-1
                    app.Data.Time = [app.Data.Time; app.Data.Time(end) + Time0];
                end
                app.Data.Time = [app.Data.Time; app.Data.Time(end) + Time0(1:dum1)];
                app.Data.Velocity = [repmat(app.Data.Velocity,dum,1); app.Data.Velocity(1:dum1)];
            end
            
            switch app.Data.SourceVelocityUnits
                case 'km/h'
                    app.Data.Velocity = app.Data.Velocity*0.277778;
                case 'mph'
                    app.Data.Velocity = app.Data.Velocity*0.44704;
            end
            
            
            % Load drive cycle as external input to the model
            app.SimInput.ExternalInput = [app.Data.Time app.Data.Velocity];
            
            % Set simulation end time
            app.SimInput = app.SimInput.setModelParameter('StopTime',num2str(app.Data.SimulationTime));
            % Set environment parameters
            app.SimInput = app.SimInput.setVariable('Pressure',app.Data.Pressure,'Workspace',app.SimModel);
            app.SimInput = app.SimInput.setVariable('Temperature',app.Data.Temperature,'Workspace',app.SimModel);
            app.SimInput = app.SimInput.setVariable('Grade',app.Data.Grade,'Workspace',app.SimModel);
            app.SimInput = app.SimInput.setVariable('WindSpeed',app.Data.WindSpeed,'Workspace',app.SimModel);
            % Set vehicle parameters
            app.SimInput = app.SimInput.setVariable('LoadedRadius',app.Data.LoadedRadius,'Workspace',app.SimModel);
            app.SimInput = app.SimInput.setVariable('UnloadedRadius',app.Data.UnloadedRadius,'Workspace',app.SimModel);
            app.SimInput = app.SimInput.setVariable('VehicleMass',app.Data.Mass,'Workspace',app.SimModel);
            app.SimInput = app.SimInput.setVariable('InitialCapacity',app.Data.BatterySOC*0.053,'Workspace',app.SimModel);
            app.SimInput = app.SimInput.setVariable('InitialVoltage',app.Data.Vinit,'Workspace',app.SimModel);
            
            % Configure for deployment
            app.SimInput = simulink.compiler.configureForDeployment(app.SimInput);
        end
        
        % Control simulation lamp color and text
        function setSimulationLamp(app,Color,Text)
            
            app.SimulationLamp.Color  = Color;
            app.StatusLabel.FontColor = Color;
            app.StatusLabel.Text      = Text;
        end
        
        % Context menu item: show results (latest, past, difference)
        function showLineMenuSelected(app, source, ~)
            
            i = str2double(source.Parent.Parent.Tag);
            % set l = line object, t = time array, d = data array
            switch source.Text
                case 'Latest Results'
                    app.ShowLatMenu(i).Checked = 'on';
                    app.HideLatMenu(i).Checked = 'on';
                    l = app.ResultLine;
                    t = app.Results.Time{1};
                    d = app.Results.Data{i};
                case 'Past Results'
                    app.ShowResMenu(i).Checked = 'on';
                    app.HideResMenu(i).Checked = 'on';
                    l = app.PastLine;
                    t = app.PastResults.Time;
                    d = app.PastResults.Data{i};
                case 'Difference'
                    app.ShowDifMenu(i).Checked = 'on';
                    app.HideDifMenu(i).Checked = 'on';
                    % Find which simulation has the lowest time
                    [~, idx] = min([app.PastResults.Time(end) app.Results.Time{1}(end)]);
                    
                    % Construct useful variables for later manipulation
                    Time = {app.PastResults.Time app.Results.Time{1}};
                    Res  = {app.PastResults.Data app.Results.Data};
                    
                    % Find unique times for interpolation
                    [Time{idx}, UniqueIdx]    = unique(Time{idx});
                    [Time{3-idx}, UniqueIdx2] = unique(Time{3-idx});
                    
                    % Construct array of results to interpolate to shorter
                    % time array
                    FittedResult   = Res{idx};
                    ResultToInterp = Res{3 - idx};
                    ResultToInterp = ResultToInterp{i}(:,UniqueIdx2);
                    
                    l = app.DiffLine;
                    t = Time{idx};
                    if i <= 3
                        d = -FittedResult{i}(:,UniqueIdx) + ...
                            interp1(Time{3 - idx},ResultToInterp(1,:),Time{idx});
                        d = [d; FittedResult{i}(:,UniqueIdx) - ...
                            interp1(Time{3 - idx},ResultToInterp(2,:),Time{idx})];
                    else
                        d = -FittedResult{i}(:,UniqueIdx) + ...
                            interp1(Time{3 - idx},ResultToInterp,Time{idx});
                    end
            end
            
            if i > 3
                set(l(1,i),'XData',t,'YData',d,'HandleVisibility','on');
            else
                set(l(1,i),'XData',t,'YData',d(1,:),'HandleVisibility','on');
                set(l(2,i),'XData',t,'YData',d(2,:),'HandleVisibility','on');
            end
        end
        
        % Value changed function: Starttimet_wot1sEditField
        
        function switchStartButtonConfiguration(app)
            
            if app.IsSimulating
                app.StartSimulationButton.Text = "Start Simulation";
                app.StartSimulationButton.Icon = 'greenarrowicon.gif';
                setSimulationLamp(app,app.Green,'Ready to simulate')
            else
                app.StartSimulationButton.Icon = 'stop_icon.png';
                app.StartSimulationButton.Text = "Stop Simulation";
            end
            app.IsSimulating = ~app.IsSimulating;
        end
        
        % Timer callback which forces screen refresh
        function updateGraphicsObjects(app,~,~)
            
            setSimulationLamp(app,'b',['Simulating (' num2str(app.SimProgressionPercentage) '%)'])
            for opIdx = 1:10
                if app.PlotQuantity(opIdx)
                    if ~isempty(app.Results.Time{opIdx})
                        if opIdx <= 3
                            set(app.ResultLine(1,opIdx),"XData",app.Results.Time{opIdx},...
                                "YData",app.Results.Data{opIdx}(1,:));
                            set(app.ResultLine(2,opIdx),"XData",app.Results.Time{opIdx},...
                                "YData",app.Results.Data{opIdx}(2,:));
                        else
                            set(app.ResultLine(1,opIdx),"XData",app.Results.Time{opIdx},...
                                "YData",app.Results.Data{opIdx});
                        end
                    end
                end
            end
            drawnow limitrate
        end
        
        % Update plot tiles
        function updatePlotTiles(app, iQuantity)
            
            % Find how many plots there need to be
            app.ActiveIndex  = find(app.PlotQuantity);
            NPrev = app.NActivePlots;
            app.NActivePlots = numel(app.ActiveIndex);
            
            % If number of plots increased, add tile, else remove that tile
            if app.NActivePlots > NPrev
                % Create tile
                app.PlotAxes(iQuantity) = nexttile(app.TileContainer);
                hold(app.PlotAxes(iQuantity),'on')
                legend(app.PlotAxes(iQuantity),'box','off');
                grid(app.PlotAxes(iQuantity),'on')
                app.PlotAxes(iQuantity).Title.String = app.PlotTitle(iQuantity);
                app.PlotAxes(iQuantity).ContextMenu  = app.ContextMenu(iQuantity);
                % Parentize the relevant lines
                if app.WasSimulating
                    createTileLines(app,iQuantity,'Old')
                    app.ShowLatMenu(iQuantity).Checked = true;
                    app.HideLatMenu(iQuantity).Checked = true;
                else
                    createTileLines(app,iQuantity,'New');
                end
            else
                % Delete tile and uncheck menu options
                delete(app.PlotAxes(iQuantity))
                app.ShowLatMenu(iQuantity).Checked = false;
                app.ShowDifMenu(iQuantity).Checked = false;
                app.ShowResMenu(iQuantity).Checked = false;
                app.HideLatMenu(iQuantity).Checked = false;
                app.HideDifMenu(iQuantity).Checked = false;
                app.HideResMenu(iQuantity).Checked = false;
            end
        end
        
        % Checks valid statement. If not valid, throws input error message
        function Out = validateStatement(app, Statement, ErrorMsg, Title)
            
            try
                assert(Statement)
                Out = true;
            catch
                uialert(app.UIFigure, ErrorMsg, Title,'Icon','error')
                Out = false;
            end
        end
        
        % Value changed function: WOTsimulationtimet_wotendEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            g = groot;
            app.UIFigure.Position = [0.1*g.ScreenSize(3:4) 0.8*g.ScreenSize(3:4)];
            app.TabGroup.Position = [0 0 app.UIFigure.Position(3:4)];
            
            start_simulink;
            % Initialize Tile Container
            app.TileContainer = tiledlayout('flow','Parent',app.VisualizationPanel,...
                'Padding','compact','TileSpacing',"compact");
            app.NActivePlots = 0;
            
            % Add listener objects
            addlistener(app,'VelocityDataChanged',@app.onVelocityDataChanged);
            addlistener(app,'DriveCycleIDChanged',@app.onDriveCycleIDChanged);
            addlistener(app,'SourceVelocityUnitsChanged',@app.onSourceVelocityUnitsChanged);
            addlistener(app,'EngineChanged',@app.onEngineChanged);
            addlistener(app,'EndSimulationTimeChanged',@app.onEndSimulationTimeChanged);
            addlistener(app,'RepeatCheckboxChanged',@app.onRepeatabilityCheckboxChanged);

            app.TimeLogLabel.Text = ['Time of login: ' datestr(datetime)];
                
            % Disable tabs
            app.DriveCycleSetupTab.Parent            = [];
            app.ModelParametersSetupTab.Parent       = [];
            app.SimulationandVisualizationTab.Parent = [];
            app.SessionLogTab.Parent                 = [];
            app.ReportTab.Parent                     = [];
            app.WideOpenThrottleWOTPanel.Parent      = [];
                        
            % Initialize graphics objects in drive cycle axes
            hold(app.DriveCycleAxes,'on')
            app.DriveCycleLine     = line(app.DriveCycleAxes,NaN,NaN,'linewidth',2,'Color','k','ButtonDownFcn',@app.lineSelectedCallback);
            app.Scatter            = scatter(app.DriveCycleAxes,NaN,NaN,50,'r','filled');
            app.EndSimulationLabel = text(app.DriveCycleAxes,0,0,'','Color','r','fontsize',15,'HorizontalAlignment','right');
            
            % Create ContextMenu for plots (one for each plot) in
            % Simulation and Visualization tab
            for i = 1:10
                app.ContextMenu(i) = uicontextmenu(app.UIFigure,'Tag',num2str(i));
                app.ShowMenu(i)    = uimenu(app.ContextMenu(i),...
                    'Text','Show');
                app.ShowResMenu(i)    = uimenu(app.ShowMenu(i),'MenuSelectedFcn',@app.showLineMenuSelected,...
                    'Text','Past Results','Enable','off');
                app.ShowLatMenu(i)    = uimenu(app.ShowMenu(i),'MenuSelectedFcn',@app.showLineMenuSelected,...
                    'Text','Latest Results','Enable','off');
                app.ShowDifMenu(i)    = uimenu(app.ShowMenu(i),'MenuSelectedFcn',@app.showLineMenuSelected,...
                    'Text','Difference','Enable','off');
                app.HideMenu(i)    = uimenu(app.ContextMenu(i),...
                    'Text','Hide');
                app.HideResMenu(i)    = uimenu(app.HideMenu(i),'MenuSelectedFcn',@app.hideLineMenuSelected,...
                    'Text','Past Results','Enable','off');
                app.HideLatMenu(i)    = uimenu(app.HideMenu(i),'MenuSelectedFcn',@app.hideLineMenuSelected,...
                    'Text','Latest Results','Enable','off');
                app.HideDifMenu(i)    = uimenu(app.HideMenu(i),'MenuSelectedFcn',@app.hideLineMenuSelected,...
                    'Text','Difference','Enable','off');
            end
            
            % Define timer that calls drawnow every 0.5 seconds
            app.Timer = timer('ExecutionMode','fixedRate','BusyMode','drop','Period',0.5,'TimerFcn',@app.updateGraphicsObjects);
        end

        % Value changed function: AtmosphericPressureSlider
        function AtmosphericPressureSliderValueChanged(app, event)
            % Convert from atm to Pascal
            app.Data.Pressure = app.AtmosphericPressureSlider.Value*101325;
        end

        % Value changed function: BatteryCurrentCheckBox
        function BatteryCurrentCheckBoxValueChanged(app, event)
            app.PlotQuantity(4) = app.BatteryCurrentCheckBox.Value;
            updatePlotTiles(app,4);
        end

        % Value changed function: BatterySOCCheckBox
        function BatterySOCCheckBoxValueChanged(app, event)
            app.PlotQuantity(5) = app.BatterySOCCheckBox.Value;
            updatePlotTiles(app,5);
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            app.PlotQuantity(:) = false;
            % Initialize all simulation plot checkboxes
            app.TPCO2MassCheckBox.Value         = false;
            app.TPCOMassCheckBox.Value          = false;
            app.TPHCMassCheckBoxn.Value          = false;
            app.TPNOxMassCheckBox.Value         = false;
            app.USFuelEconomyCheckBox.Value     = false;
            app.BatterySOCCheckBox.Value        = false;
            app.BatteryCurrentCheckBox.Value    = false;
            app.EngineMotorTorqueCheckBox.Value = false;
            app.EngineMotorSpeedCheckBoxnn.Value  = false;
            app.VelocityCheckBox.Value          = false;
            
            % Update number of active simulation plots
            for i = 1:10
                delete(app.PlotAxes(i));
                app.ShowLatMenu(i).Checked = false;
                app.ShowResMenu(i).Checked = false;
                app.ShowDifMenu(i).Checked = false;
                app.HideLatMenu(i).Checked = false;
                app.HideResMenu(i).Checked = false;
                app.HideDifMenu(i).Checked = false;
            end
            
            % Reset number of active plots
            app.NActivePlots = 0;
        end

        % Button pushed function: ClearScatterPointButton
        function ClearScatterPointButtonPushed(app, event)
            % Clear scatter point
            app.Scatter.XData = [];
            app.Scatter.YData = [];
        end

        % Menu selected function: DocumentationonHEVP4Menu
        function DocumentationonHEVP4MenuSelected(app, event)
            % Open Documentation page on HEV P4 model
            web('https://mathworks.com/help/autoblks/ug/explore-the-hybrid-electric-vehicle-p4-reference-application.html')
        end

        % Value changed function: DriveCycleFromDropDown
        function DriveCycleFromDropDownValueChanged(app, event)
            % If valid selection, then update the drive cycle
            if ~strcmp(app.DriveCycleFromDropDown.Value,app.DriveCycleFromDropDown.Items{1})
                app.Data.DriveCycleID = app.DriveCycleFromDropDown.Value;
                notify(app,'DriveCycleIDChanged')
                
                % Load Time and Velocity Data
                if strcmp(app.DriveCycleFromDropDown.Value,'FTP75')
                    loadFTP75DriveCycle(app)
                else
                    generateWOTCurve(app)
                end
                
                % Set Velocity Units
                if strcmp(app.DriveCycleFromDropDown.Value,'FTP75')
                    app.Data.SourceVelocityUnits = 'm/s';
                else
                    app.Data.SourceVelocityUnits = app.SourceVelocityUnitsDropDown.Items{1};
                end
                notify(app,'SourceVelocityUnitsChanged')
            end
        end

        % Button pushed function: DriveCycleFromFileButton
        function DriveCycleFromFileButtonPushed(app, event)
            % Select Drive Cycle file
            [file, path] = uigetfile({'*.mat';'*.xlsx';'*xls';'*.txt'});
            
            % If valid, load drive cycle and update dependent components
            if isValidDriveCycle(app,[path file])
                app.Data.DriveCycleID = [path file];
                notify(app,'DriveCycleIDChanged')
                [~,FileName,FileExtension] = fileparts(file);
                % Import drive cycle differently, depending on the file
                % format (MAT, Excel or text)
                switch FileExtension
                    case '.mat'
                        s = load([path file]);
                        Array = s.(FileName);
                        app.Data.Time     = Array(:,1);
                        app.Data.Velocity = Array(:,2);
                    case {'.xls','.xlsx'}
                        s = readtable([path file],'PreserveVariableNames',true);
                        vars = s.Variables;
                        app.Data.Time     = vars(:,1);
                        app.Data.Velocity = vars(:,2);
                    case '.txt'
                        s = readtable([path file]);
                        vars = s.Variables;
                        app.Data.Time     = vars(:,1);
                        app.Data.Velocity = vars(:,2);
                end
                notify(app,'VelocityDataChanged')
                app.Data.SimulationTime = app.Data.Time(end);
                notify(app,'EndSimulationTimeChanged')
                app.Data.SourceVelocityUnits = app.SourceVelocityUnitsDropDown.Items{1};
                notify(app,'SourceVelocityUnitsChanged')
                app.DriveCycleFromDropDown.Value = app.DriveCycleFromDropDown.Items{1};
                % Clear scatter point
                ClearScatterPointButtonPushed(app,[])
            end
        end

        % Cell edit callback: DriveCycleTable
        function DriveCycleTableCellEdit(app, event)
            % Update Data.Velocity and drive cycle plot
            app.Data.Velocity(event.Indices(1)) = event.NewData;
            notify(app,'VelocityDataChanged')
        end

        % Cell selection callback: DriveCycleTable
        function DriveCycleTableCellSelection(app, event)
            % Add a scatter plot of the selected data point
            app.Scatter.XData = app.Data.Time(event.Indices(1));
            app.Scatter.YData = app.Data.Velocity(event.Indices(1));
        end

        % Value changed function: EndSimulationTimeEditField
        function EndSimulationTimeEditFieldValueChanged(app, event)
            % Update Data.SimulationTime and plots in Simulation and
            % Visualization tab
            app.Data.SimulationTime = app.EndSimulationTimeEditField.Value;
            notify(app,'EndSimulationTimeChanged')
        end

        % Value changed function: EngineMotorSpeedCheckBoxnn
        function EngineMotorSpeedCheckBoxnnValueChanged(app, event)
            app.PlotQuantity(2) = app.EngineMotorSpeedCheckBoxnn.Value;
            updatePlotTiles(app,2);
        end

        % Value changed function: EngineMotorTorqueCheckBox
        function EngineMotorTorqueCheckBoxValueChanged(app, event)
            app.PlotQuantity(3) = app.EngineMotorTorqueCheckBox.Value;
            updatePlotTiles(app,3);
        end

        % Menu selected function: ExportResultsMenu
        function ExportResultsMenuSelected(app, event)
            % Enter file name and choose where to save
            [filename, path, Ext] = uiputfile({'*.mat','MAT-files (*.mat)';'*.xlsx', 'Excel (*.xlsx)'},...
                [],'NewSimulationResults');
            
            if ~isnumeric([path, filename])
                Results = app.Results; %#ok<ADPROPLC>
                if isequal(Ext,2)
                    % Save to Excel spreadsheet
                    TabData = [];
                    VarName{1} = 'Time (s)';
                    VarName{2} = 'Target Trace Velocity (mph)';
                    VarName{3} = 'Actual Trace Velocity (mph)';
                    VarName{4} = 'EngSpd (RPM)';
                    VarName{5} = 'MotSpd (RPM)';
                    VarName{6} = 'EngTrq (Nm)';
                    VarName{7} = 'MotTrq (Nm)';
                    for i = 8:14
                        VarName{i} = app.PlotTitle{i-4};
                    end
                    for i = 1:10
                        tab = app.Results.Data{i};
                        TabData = [TabData tab]; %#ok<AGROW>
                    end
                    T = table(app.Results.Time{1}, TabData);
                    T = splitvars(T,'Var2','NewVariableNames',VarName(2:end));
                    T.Properties.VariableNames{1} = 'Time (s)';
                    writetable(T,[path filename]);
                else
                    % Save to a mat file
                    save([path, filename],'-struct','Results')
                end
                addLogMessage(app,'ExportedResults',[path, filename]);
            else
                % User selected "Cancel" and did not specify a path
                return
            end
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            % The axis called by the report generator (in Report Tab) needs to have a
            % parent
            app.ReportTab.Parent = app.TabGroup;
            
            ProgressBar = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Creating simulation report...','ShowPercentage',true);
            % Create simulation report
            fid = HEVReportGen(app.Data,app.Results,app.Username,app.ReportAxes,app.DriveCycleAxes,ProgressBar);
            
            ProgressBar.Value = 1;
            pause(1)
            close(ProgressBar)
            
            if ~fid
                % Report generation failed
                uialert(app.UIFigure,lasterr,'Error') %#ok<*LERR>
                addLogMessage(app, "ReportFailed")
            else
                % Report generation successful
                uialert(app.UIFigure,"Report generated successfully.","Report Outcome","Icon","success")
                addLogMessage(app, "ReportGenerated")
            end
            app.ReportTab.Parent = app.TabGroup;
        end

        % Value changed function: GradeEditField
        function GradeEditFieldValueChanged(app, event)
            app.Data.Grade = app.GradeEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.isSession
                Answer = askToSaveCurrentSession(app);
                if strcmp(Answer,'Cancel')
                    return
                end
            end
            delete(app)
        end

        % Value changed function: InitialBatterySOCSlider
        function InitialBatterySOCSliderValueChanged(app, event)
            % Update data structure
            app.Data.BatterySOC = app.InitialBatterySOCSlider.Value;
            
            % Update battery table, which contains initial battery capacity,
            % which depends on initial SOC
            s = load('Battery_param.mat');
            BattCapInit    = app.Data.BatterySOC*s.('BattChargeMax')/100;
            app.Data.Vinit = s.('Ns')*interp1(s.('CapLUTBp'),s.('Em'),BattCapInit/s.('BattChargeMax'));
            app.BatteryTable.Data(contains(app.BatteryTable.Data(:,1),'Vinit'),2) = {num2str(app.Data.Vinit)};
            app.BatteryTable.Data(contains(app.BatteryTable.Data(:,1),'BattCapInit'),2) = {num2str(BattCapInit)};
        end

        % Value changed function: LoadedWheelRadiusEditField
        function LoadedWheelRadiusEditFieldValueChanged(app, event)
            app.Data.LoadedRadius = app.LoadedWheelRadiusEditField.Value;
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            loadSession(app)
        end

        % Menu selected function: LoadResultsMenu
        function LoadResultsMenuSelected(app, event)
            % Select session file
            [file, path] = uigetfile({'*.mat','MAT-files (*.mat)';'*.xlsx', 'Excel (*.xlsx)'},'Select a File');
            
            if isValidResultsFile(app,[path file])
                if ~strcmp(file(end),'x')
                    % Load mat file
                    s = load([path file]);
                    app.PastResults.Time = s.Time{1};
                    app.PastResults.Data = s.Data;
                else
                    s = readtable([path file],'PreserveVariableNames',true);
                    app.PastResults.Time    = s{:,1};
                    app.PastResults.Data{1} = s{:,[2 3]};
                    app.PastResults.Data{2} = s{:,[4 5]};
                    app.PastResults.Data{3} = s{:,[6 7]};
                    for i = 4:10
                        app.PastResults.Data{i} = s{:,i+4};
                    end
                end
                app.isPastResultsLoaded = true;
                addLogMessage(app,'LoadedResults',[path file]);
                % Enable context menu items
                for i = 1:10
                    app.ShowResMenu(i).Enable = true;
                    app.HideResMenu(i).Enable = true;
                end
                % Enable diff menu only if a simulation was run before
                if app.WasSimulating
                    for i = 1:10
                        app.ShowDifMenu(i).Enable = true;
                        app.HideDifMenu(i).Enable = true;
                    end
                end
                % Create PastResultsLabel
                if isempty(app.PastResultsLabel)
                    app.GridLayout18.RowHeight = {'1x', 'fit', 'fit'};
                    app.PastResultsLabel = uilabel(app.GridLayout18);
                    app.PastResultsLabel.HorizontalAlignment = 'center';
                    app.PastResultsLabel.Layout.Row = 3;
                    app.PastResultsLabel.Layout.Column = [1 11];
                    app.PastResultsLabel.Text = ['Past results loaded from: ' file];
                else
                    app.PastResultsLabel.Text = ['Past results loaded from: ' file];
                end
            end
        end

        % Button pushed function: LoadSessionButton
        function LoadSessionButtonPushed(app, event)
            loadSession(app)
        end

        % Value changed function: LongitudinalWindSpeedEditField
        function LongitudinalWindSpeedEditFieldValueChanged(app, event)
            app.Data.WindSpeed = app.LongitudinalWindSpeedEditField.Value;
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            createNewSession(app)
        end

        % Button pushed function: NewSessionButton
        function NewSessionButtonPushed(app, event)
            createNewSession(app)
        end

        % Callback function
        function RepeatcyclicallyCheckBoxValueChanged(app, event)
            % Cannot uncheck this option if simulation time is greater than
            % the drive cycle period
            if ~app.RepeatcyclicallyCheckBox.Value && (app.Data.Time(end) < app.Data.SimulationTime)
                uialert(app.UIFigure,...
                    'Simulation time cannot exceed drive cycle period if not repeating cyclically.',...
                    'Parameter Selected Not Allowed','icon','error')
                app.RepeatcyclicallyCheckBox.Value = true;
            else
                % Update Data.RepeatCyclicallyCheckbox and set the visibility
                % of the dependent component
                app.Data.RepeatCyclicallyCheckbox = app.RepeatcyclicallyCheckBox.Value;
                notify(app,'RepeatCheckboxChanged')
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            saveNewSession(app);
            app.isSavedSession = true;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if ~app.isSavedSession
                % Session is unsaved
                saveNewSession(app);
            else
                % Save to current filename
                saveCurrentSession(app)
                addLogMessage(app,'SavedSession');
            end
        end

        % Value changed function: SelectEngineTypeDropDown
        function SelectEngineTypeDropDownValueChanged(app, event)
            app.Data.Engine = app.SelectEngineTypeDropDown.Value;
            notify(app,'EngineChanged')
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            if app.IsSimulating
                simulink.compiler.stopSimulation(app.SimModel);
                app.SimulationAborted = true;
                stop(app.Timer)
            else
                switchStartButtonConfiguration(app)
                % Change color of simulation lamp
                setSimulationLamp(app,[1 0.647 0],'Preparing the simulation...')
                % Disable components
                app.ExportResultsMenu.Enable = false;
                
                % Set simulation parameters
                setModelParametersToSimulinkModel(app)
                
                addLogMessage(app,'SimulationStarted');
                drawnow
                
                for i = 1:10
                    app.Results.Time{i} = [];
                    app.Results.Data{i} = [];
                end
                % Attempt to run simulation in try/catch. If the simulation
                % fails, then the error is displayed in a popup window.
                try
                    app.SimulationAborted = false;
                    % Run simulation
                    start(app.Timer)
                    sim(app.SimInput);
                    stop(app.Timer)
                    if app.SimulationAborted
                        addLogMessage(app,'SimulationNotCompleted');
                    else
                        runEndSimulationProcedures(app)
                    end
                    switchStartButtonConfiguration(app)
                    app.WasSimulating = true;
                catch e
                    uialert(app.UIFigure,[e.message ' ' e.cause{1}.message],'Simulation Crashed')
                    addLogMessage(app,'SimulationTerminated');
                    % Initialize components
                    stop(app.Timer)
                    switchStartButtonConfiguration(app)
                    setSimulationLamp(app,app.Green,'Ready to simulate')
                end
            end
            app.SimProgressionPercentage = 0;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Check that source velocity units and output velocity units
            % are selected
            if strcmp(app.TabGroup.SelectedTab.Title,'Simulation and Visualization')
                app.StatusLabel.Text      = 'Ready to simulate';
                app.StatusLabel.FontColor = app.Green;
                app.SimulationLamp.Color  = app.Green;
                app.StartSimulationButton.Enable = true;
                % Check that source velocity is selected
                try
                    assert(~strcmp(app.Data.SourceVelocityUnits,'Select...'))
                catch
                    app.StatusLabel.Text      = 'Source velocity units unselected';
                    app.StatusLabel.FontColor = 'r';
                    app.SimulationLamp.Color  = 'r';
                    app.StartSimulationButton.Enable = false;
                end
            end
        end

        % Value changed function: TemperatureSpinner
        function TemperatureSpinnerValueChanged(app, event)
            app.Data.Temperature = app.TemperatureSpinner.Value;
        end

        % Value changed function: TPCO2MassCheckBox
        function TPCO2MassCheckBoxValueChanged(app, event)
            app.PlotQuantity(10) = app.TPCO2MassCheckBox.Value;
            updatePlotTiles(app,10);
        end

        % Value changed function: TPCOMassCheckBox
        function TPCOMassCheckBoxValueChanged(app, event)
            app.PlotQuantity(8) = app.TPCOMassCheckBox.Value;
            updatePlotTiles(app,8);
        end

        % Value changed function: TPHCMassCheckBoxn
        function TPHCMassCheckBoxnValueChanged(app, event)
            app.PlotQuantity(7) = app.TPHCMassCheckBoxn.Value;
            updatePlotTiles(app,7);
        end

        % Value changed function: TPNOxMassCheckBox
        function TPNOxMassCheckBoxValueChanged(app, event)
            app.PlotQuantity(9) = app.TPNOxMassCheckBox.Value;
            updatePlotTiles(app,9);
        end

        % Value changed function: UnloadedWheelRadiusEditField
        function UnloadedWheelRadiusEditFieldValueChanged(app, event)
            
            app.Data.UnloadedRadius = app.UnloadedWheelRadiusEditField.Value;
        end

        % Value changed function: USFuelEconomyCheckBox
        function USFuelEconomyCheckBoxValueChanged(app, event)
            app.PlotQuantity(6) = app.USFuelEconomyCheckBox.Value;
            updatePlotTiles(app,6);
        end

        % Value changed function: VehicleMassEditField
        function VehicleMassEditFieldValueChanged(app, event)
            app.Data.Mass = app.VehicleMassEditField.Value;
        end

        % Value changed function: VelocityCheckBox
        function VelocityCheckBoxValueChanged(app, event)
            app.PlotQuantity(1) = app.VelocityCheckBox.Value;
            updatePlotTiles(app,1);
        end

        % Value changed function: Starttimet_wot1sEditField
        function Starttimet_wot1sEditFieldValueChanged(app, event)
            app.Data.Starttimet_wot1s = app.Starttimet_wot1sEditField.Value;
            generateWOTCurve(app)
        end

        % Value changed function: 
        % Initialreferencespeedxdot_wotoEditField
        function Initialreferencespeedxdot_wotoEditFieldValueChanged(app, event)
            app.Data.Initialreferencespeedxdot_woto = app.Initialreferencespeedxdot_wotoEditField.Value;
            generateWOTCurve(app)
        end

        % Value changed function: 
        % Nominalreferencespeedxdot_wot1EditField
        function Nominalreferencespeedxdot_wot1EditFieldValueChanged(app, event)
            app.Data.Nominalreferencespeedxdot_wot1 = app.Nominalreferencespeedxdot_wot1EditField.Value;
            % Update drive cycle curve
            generateWOTCurve(app)
        end

        % Value changed function: 
        % Timetostartdecelerationt_wot2sEditField
        function Timetostartdecelerationt_wot2sEditFieldValueChanged(app, event)
            app.Data.Timetostartdecelerationt_wot2s = app.Timetostartdecelerationt_wot2sEditField.Value;
            generateWOTCurve(app)
        end

        % Value changed function: 
        % Finalreferencespeedxdot_wot2EditField
        function Finalreferencespeedxdot_wot2EditFieldValueChanged(app, event)
            app.Data.Finalreferencespeedxdot_wot2 = app.Finalreferencespeedxdot_wot2EditField.Value;
            % Update drive cycle curve
            generateWOTCurve(app)
        end

        % Value changed function: WOTsimulationtimet_wotendEditField
        function WOTsimulationtimet_wotendEditFieldValueChanged(app, event)
            app.Data.WOTsimulationtimet_wotend = app.WOTsimulationtimet_wotendEditField.Value;
            % Update drive cycle plot
            generateWOTCurve(app)
        end

        % Value changed function: SourceVelocityUnitsDropDown
        function SourceVelocityUnitsDropDownValueChanged(app, event)
            app.Data.SourceVelocityUnits = app.SourceVelocityUnitsDropDown.Value;
            notify(app,'SourceVelocityUnitsChanged')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'HEV P4 Reference Application';
            app.UIFigure.Icon = 'plotpicker-idnlhwplot.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SessionMenu = uimenu(app.UIFigure);
            app.SessionMenu.Text = 'Session';
            app.NewMenu = uimenu(app.SessionMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.SessionMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load...';
            app.SaveMenu = uimenu(app.SessionMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Visible = 'off';
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.SessionMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Visible = 'off';
            app.SaveAsMenu.Text = 'Save As...';
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.Visible = 'off';
            app.SimulationMenu.Text = 'Simulation';
            app.ExportResultsMenu = uimenu(app.SimulationMenu);
            app.ExportResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResultsMenuSelected, true);
            app.ExportResultsMenu.Enable = 'off';
            app.ExportResultsMenu.Text = 'Export Results...';
            app.LoadResultsMenu = uimenu(app.SimulationMenu);
            app.LoadResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadResultsMenuSelected, true);
            app.LoadResultsMenu.Text = 'Load Results...';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationonHEVP4Menu = uimenu(app.HelpMenu);
            app.DocumentationonHEVP4Menu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationonHEVP4MenuSelected, true);
            app.DocumentationonHEVP4Menu.Text = 'Documentation on HEV P4';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.GridLayout37 = uigridlayout(app.StartTab);
            app.GridLayout38 = uigridlayout(app.GridLayout37);
            app.LoginImage = uiimage(app.GridLayout38);
            app.LoginImage.Tooltip = {'Schematics of the HEV P4 Reference Model in Simulink'};
            app.LoginImage.ImageSource = 'model.JPG';
            app.WelcomeLabel = uilabel(app.GridLayout38);
            app.WelcomeLabel.Text = 'Welcome! Start a new session or load a pre-saved session';
            app.NewSessionButton = uibutton(app.GridLayout38, 'push');
            app.NewSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButtonPushed, true);
            app.NewSessionButton.Tooltip = {'Click to start a new session'};
            app.NewSessionButton.Text = 'New Session';
            app.LoadSessionButton = uibutton(app.GridLayout38, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionButtonPushed, true);
            app.LoadSessionButton.Tooltip = {'Click to load a saved session'};
            app.LoadSessionButton.Text = 'Load Session';
            app.DriveCycleSetupTab = uitab(app.TabGroup);
            app.DriveCycleSetupTab.Title = 'Drive Cycle Setup';
            app.GridLayout29 = uigridlayout(app.DriveCycleSetupTab);
            app.GridLayout30 = uigridlayout(app.GridLayout29);
            app.SelectdrivecycleLabel = uilabel(app.GridLayout30);
            app.SelectdrivecycleLabel.Text = 'Select drive cycle:';
            app.DriveCycleFromDropDown = uidropdown(app.GridLayout30);
            app.DriveCycleFromDropDown.Items = {'From MATLAB...', 'FTP75', 'Wide Open Throttle (WOT)'};
            app.DriveCycleFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DriveCycleFromDropDownValueChanged, true);
            app.DriveCycleFromDropDown.Tooltip = {'Choose from pre-defined drive cycles'};
            app.DriveCycleFromDropDown.Value = 'From MATLAB...';
            app.DriveCycleFromFileButton = uibutton(app.GridLayout30, 'push');
            app.DriveCycleFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @DriveCycleFromFileButtonPushed, true);
            app.DriveCycleFromFileButton.Tooltip = {'Import drive cycle from file (mat, xlsx, xls, txt)'};
            app.DriveCycleFromFileButton.Text = 'From File...';
            app.DriveCycleOptionsPanel = uipanel(app.GridLayout29);
            app.DriveCycleOptionsPanel.Title = '   Drive Cycle Options';
            app.GridLayout46 = uigridlayout(app.DriveCycleOptionsPanel);
            app.GridLayout47 = uigridlayout(app.GridLayout46);
            app.EndSimulationTimesEditFieldLabel = uilabel(app.GridLayout47);
            app.EndSimulationTimesEditFieldLabel.Text = 'End Simulation Time (s):';
            app.EndSimulationTimeEditField = uieditfield(app.GridLayout47, 'numeric');
            app.EndSimulationTimeEditField.Limits = [0 Inf];
            app.EndSimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndSimulationTimeEditFieldValueChanged, true);
            app.RepeatcyclicallyCheckBox = uicheckbox(app.GridLayout47);
            app.RepeatcyclicallyCheckBox.Enable = 'off';
            app.RepeatcyclicallyCheckBox.Text = 'Repeat cyclically';
            app.WideOpenThrottleWOTPanel = uipanel(app.GridLayout46);
            app.WideOpenThrottleWOTPanel.Title = '   Wide Open Throttle (WOT)';
            app.GridLayout49 = uigridlayout(app.WideOpenThrottleWOTPanel);
            app.Starttimet_wot1sEditFieldLabel = uilabel(app.GridLayout49);
            app.Starttimet_wot1sEditFieldLabel.Text = 'Start time, t_wot1 [s]:';
            app.Starttimet_wot1sEditField = uieditfield(app.GridLayout49, 'numeric');
            app.Starttimet_wot1sEditField.ValueChangedFcn = createCallbackFcn(app, @Starttimet_wot1sEditFieldValueChanged, true);
            app.Starttimet_wot1sEditField.Value = 1;
            app.Timetostartdecelerationt_wot2sEditFieldLabel = uilabel(app.GridLayout49);
            app.Timetostartdecelerationt_wot2sEditFieldLabel.Text = 'Time to start deceleration, t_wot2 [s]:';
            app.Timetostartdecelerationt_wot2sEditField = uieditfield(app.GridLayout49, 'numeric');
            app.Timetostartdecelerationt_wot2sEditField.ValueChangedFcn = createCallbackFcn(app, @Timetostartdecelerationt_wot2sEditFieldValueChanged, true);
            app.Timetostartdecelerationt_wot2sEditField.Value = 30;
            app.Initialreferencespeedxdot_wotoEditFieldLabel = uilabel(app.GridLayout49);
            app.Initialreferencespeedxdot_wotoEditFieldLabel.Text = 'Initial reference speed, xdot_woto []:';
            app.Initialreferencespeedxdot_wotoEditField = uieditfield(app.GridLayout49, 'numeric');
            app.Initialreferencespeedxdot_wotoEditField.ValueChangedFcn = createCallbackFcn(app, @Initialreferencespeedxdot_wotoEditFieldValueChanged, true);
            app.Finalreferencespeedxdot_wot2EditFieldLabel = uilabel(app.GridLayout49);
            app.Finalreferencespeedxdot_wot2EditFieldLabel.Text = 'Final reference speed, xdot_wot2 []:';
            app.Finalreferencespeedxdot_wot2EditField = uieditfield(app.GridLayout49, 'numeric');
            app.Finalreferencespeedxdot_wot2EditField.ValueChangedFcn = createCallbackFcn(app, @Finalreferencespeedxdot_wot2EditFieldValueChanged, true);
            app.Nominalreferencespeedxdot_wot1EditFieldLabel = uilabel(app.GridLayout49);
            app.Nominalreferencespeedxdot_wot1EditFieldLabel.Text = 'Nominal reference speed, xdot_wot1 []:';
            app.Nominalreferencespeedxdot_wot1EditField = uieditfield(app.GridLayout49, 'numeric');
            app.Nominalreferencespeedxdot_wot1EditField.ValueChangedFcn = createCallbackFcn(app, @Nominalreferencespeedxdot_wot1EditFieldValueChanged, true);
            app.Nominalreferencespeedxdot_wot1EditField.Value = 26.8;
            app.WOTsimulationtimet_wotendEditFieldLabel = uilabel(app.GridLayout49);
            app.WOTsimulationtimet_wotendEditFieldLabel.Text = 'WOT simulation time, t_wotend []:';
            app.WOTsimulationtimet_wotendEditField = uieditfield(app.GridLayout49, 'numeric');
            app.WOTsimulationtimet_wotendEditField.ValueChangedFcn = createCallbackFcn(app, @WOTsimulationtimet_wotendEditFieldValueChanged, true);
            app.WOTsimulationtimet_wotendEditField.Value = 40;
            app.SourceVelocityUnitsDropDownLabel = uilabel(app.GridLayout49);
            app.SourceVelocityUnitsDropDownLabel.Text = 'Source Velocity Units:';
            app.SourceVelocityUnitsDropDown = uidropdown(app.GridLayout49);
            app.SourceVelocityUnitsDropDown.Items = {'Select...', 'm/s', 'km/h', 'mph'};
            app.SourceVelocityUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceVelocityUnitsDropDownValueChanged, true);
            app.SourceVelocityUnitsDropDown.Value = 'Select...';
            app.GridLayout31 = uigridlayout(app.GridLayout29);
            app.DriveCycleAxes = uiaxes(app.GridLayout31);
            title(app.DriveCycleAxes, 'FTP75 Drive Cycle')
            xlabel(app.DriveCycleAxes, 'Time (s)')
            ylabel(app.DriveCycleAxes, 'Y')
            app.GridLayout32 = uigridlayout(app.GridLayout31);
            app.ClearScatterPointButton = uibutton(app.GridLayout32, 'push');
            app.ClearScatterPointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearScatterPointButtonPushed, true);
            app.ClearScatterPointButton.Tooltip = {'Clear scatter point in the right plot'};
            app.ClearScatterPointButton.Text = 'Clear Scatter Point';
            app.DriveCycleTable = uitable(app.GridLayout32);
            app.DriveCycleTable.ColumnName = {'Time (s)'; 'Velocity (m/s)'};
            app.DriveCycleTable.RowName = {};
            app.DriveCycleTable.ColumnEditable = [false true];
            app.DriveCycleTable.CellEditCallback = createCallbackFcn(app, @DriveCycleTableCellEdit, true);
            app.DriveCycleTable.CellSelectionCallback = createCallbackFcn(app, @DriveCycleTableCellSelection, true);
            app.ModelParametersSetupTab = uitab(app.TabGroup);
            app.ModelParametersSetupTab.Title = 'Model Parameters Setup';
            app.GridLayout33 = uigridlayout(app.ModelParametersSetupTab);
            app.EnvironmentParametersPanel = uipanel(app.GridLayout33);
            app.EnvironmentParametersPanel.Title = '   Environment Parameters';
            app.GridLayout13 = uigridlayout(app.EnvironmentParametersPanel);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.GradeEditField = uieditfield(app.GridLayout14, 'numeric');
            app.GradeEditField.Limits = [0 Inf];
            app.GradeEditField.ValueChangedFcn = createCallbackFcn(app, @GradeEditFieldValueChanged, true);
            app.TemperatureSpinner = uispinner(app.GridLayout14);
            app.TemperatureSpinner.Limits = [0 Inf];
            app.TemperatureSpinner.ValueChangedFcn = createCallbackFcn(app, @TemperatureSpinnerValueChanged, true);
            app.TemperatureSpinner.Value = 300;
            app.LongitudinalWindSpeedEditField = uieditfield(app.GridLayout14, 'numeric');
            app.LongitudinalWindSpeedEditField.Limits = [0 Inf];
            app.LongitudinalWindSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudinalWindSpeedEditFieldValueChanged, true);
            app.GradeLabel = uilabel(app.GridLayout14);
            app.GradeLabel.Text = 'Grade (deg):';
            app.LongitudinalWindSpeedLabel = uilabel(app.GridLayout14);
            app.LongitudinalWindSpeedLabel.Text = 'Longitudinal Wind Speed (m/s):';
            app.TemperatureLabel = uilabel(app.GridLayout14);
            app.TemperatureLabel.Text = 'Temperature (K):';
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.AtmosphericPressureSlider = uislider(app.GridLayout15);
            app.AtmosphericPressureSlider.Limits = [0.6 1];
            app.AtmosphericPressureSlider.MajorTicks = [0.608 0.6898 0.785 0.886 1];
            app.AtmosphericPressureSlider.MajorTickLabels = {'0.608 (4000 m)', '0.690 (3000 m)', '0.785 (2000 m)', '0.886 (1000 m)', '1.000 (Sea Level)'};
            app.AtmosphericPressureSlider.ValueChangedFcn = createCallbackFcn(app, @AtmosphericPressureSliderValueChanged, true);
            app.AtmosphericPressureSlider.MinorTicks = [];
            app.AtmosphericPressureSlider.Value = 1;
            app.AtmosphericPressureLabel = uilabel(app.GridLayout15);
            app.AtmosphericPressureLabel.Text = 'Atmospheric Pressure (atm)';
            app.VehicleParametersPanel = uipanel(app.GridLayout33);
            app.VehicleParametersPanel.Title = '   Vehicle Parameters';
            app.GridLayout23 = uigridlayout(app.VehicleParametersPanel);
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.LoadedWheelRadiusEditField = uieditfield(app.GridLayout24, 'numeric');
            app.LoadedWheelRadiusEditField.Limits = [0 Inf];
            app.LoadedWheelRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LoadedWheelRadiusEditFieldValueChanged, true);
            app.UnloadedWheelRadiusEditField = uieditfield(app.GridLayout24, 'numeric');
            app.UnloadedWheelRadiusEditField.Limits = [0 Inf];
            app.UnloadedWheelRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @UnloadedWheelRadiusEditFieldValueChanged, true);
            app.VehicleMassEditField = uieditfield(app.GridLayout24, 'numeric');
            app.VehicleMassEditField.Limits = [0 Inf];
            app.VehicleMassEditField.ValueChangedFcn = createCallbackFcn(app, @VehicleMassEditFieldValueChanged, true);
            app.LoadedWheelRadiusLabel = uilabel(app.GridLayout24);
            app.LoadedWheelRadiusLabel.Text = 'Loaded Wheel Radius (m):';
            app.UnloadedWheelRadiusLabel = uilabel(app.GridLayout24);
            app.UnloadedWheelRadiusLabel.Text = 'Unloaded Wheel Radius (m):';
            app.VehicleMassLabel = uilabel(app.GridLayout24);
            app.VehicleMassLabel.Text = 'Vehicle Mass (kg):';
            app.GridLayout39 = uigridlayout(app.GridLayout23);
            app.InitialBatterySOCSlider = uislider(app.GridLayout39);
            app.InitialBatterySOCSlider.ValueChangedFcn = createCallbackFcn(app, @InitialBatterySOCSliderValueChanged, true);
            app.InitialBatterySOCLabel = uilabel(app.GridLayout39);
            app.InitialBatterySOCLabel.Text = 'Initial Battery SOC (%)';
            app.BatteryPanel = uipanel(app.GridLayout33);
            app.BatteryPanel.Title = '   Battery';
            app.GridLayout12 = uigridlayout(app.BatteryPanel);
            app.BatteryTypeLabel = uilabel(app.GridLayout12);
            app.BatteryTypeLabel.Text = '  Battery type: Lithium Ion';
            app.BatteryTable = uitable(app.GridLayout12);
            app.BatteryTable.ColumnName = {'Parameter'; 'Value'};
            app.BatteryTable.RowName = {};
            app.EnginePanel = uipanel(app.GridLayout33);
            app.EnginePanel.Title = '   Engine';
            app.GridLayout34 = uigridlayout(app.EnginePanel);
            app.GridLayout35 = uigridlayout(app.GridLayout34);
            app.SelectEngineTypeLabel = uilabel(app.GridLayout35);
            app.SelectEngineTypeLabel.Text = 'Select Engine Type:';
            app.SelectEngineTypeDropDown = uidropdown(app.GridLayout35);
            app.SelectEngineTypeDropDown.Items = {'SiMappedEngine', 'SiEngine'};
            app.SelectEngineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEngineTypeDropDownValueChanged, true);
            app.SelectEngineTypeDropDown.Tooltip = {'Select engine type'};
            app.SelectEngineTypeDropDown.Value = 'SiMappedEngine';
            app.GridLayout36 = uigridlayout(app.GridLayout34);
            app.ECMLabel = uilabel(app.GridLayout36);
            app.ECMLabel.Text = 'Engine Controller Module (ECM):';
            app.EngineTable = uitable(app.GridLayout36);
            app.EngineTable.ColumnName = {'Parameter'; 'Value'};
            app.EngineTable.RowName = {};
            app.SimulationandVisualizationTab = uitab(app.TabGroup);
            app.SimulationandVisualizationTab.Title = 'Simulation and Visualization';
            app.GridLayout16 = uigridlayout(app.SimulationandVisualizationTab);
            app.VisualizationPanel = uipanel(app.GridLayout16);
            app.VisualizationPanel.Title = '   Visualization';
            app.GridLayout18 = uigridlayout(app.GridLayout16);
            app.VelocityCheckBox = uicheckbox(app.GridLayout18);
            app.VelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @VelocityCheckBoxValueChanged, true);
            app.VelocityCheckBox.Text = 'Velocity';
            app.OutputplotsLabel = uilabel(app.GridLayout18);
            app.OutputplotsLabel.Text = 'Output plots:';
            app.EngineMotorSpeedCheckBoxnn = uicheckbox(app.GridLayout18);
            app.EngineMotorSpeedCheckBoxnn.ValueChangedFcn = createCallbackFcn(app, @EngineMotorSpeedCheckBoxnnValueChanged, true);
            app.EngineMotorSpeedCheckBoxnn.Text = 'Engine / Motor Speed (RPM)';
            app.EngineMotorTorqueCheckBox = uicheckbox(app.GridLayout18);
            app.EngineMotorTorqueCheckBox.ValueChangedFcn = createCallbackFcn(app, @EngineMotorTorqueCheckBoxValueChanged, true);
            app.EngineMotorTorqueCheckBox.Text = 'Engine / Motor Torque (Nm)';
            app.BatteryCurrentCheckBox = uicheckbox(app.GridLayout18);
            app.BatteryCurrentCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatteryCurrentCheckBoxValueChanged, true);
            app.BatteryCurrentCheckBox.Text = 'Battery Current (A)';
            app.BatterySOCCheckBox = uicheckbox(app.GridLayout18);
            app.BatterySOCCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatterySOCCheckBoxValueChanged, true);
            app.BatterySOCCheckBox.Text = 'Battery SOC';
            app.USFuelEconomyCheckBox = uicheckbox(app.GridLayout18);
            app.USFuelEconomyCheckBox.ValueChangedFcn = createCallbackFcn(app, @USFuelEconomyCheckBoxValueChanged, true);
            app.USFuelEconomyCheckBox.Text = 'US Fuel Economy (MPGe)';
            app.TPHCMassCheckBoxn = uicheckbox(app.GridLayout18);
            app.TPHCMassCheckBoxn.ValueChangedFcn = createCallbackFcn(app, @TPHCMassCheckBoxnValueChanged, true);
            app.TPHCMassCheckBoxn.Text = 'TP HC Mass';
            app.TPCOMassCheckBox = uicheckbox(app.GridLayout18);
            app.TPCOMassCheckBox.ValueChangedFcn = createCallbackFcn(app, @TPCOMassCheckBoxValueChanged, true);
            app.TPCOMassCheckBox.Text = 'TP CO Mass';
            app.TPNOxMassCheckBox = uicheckbox(app.GridLayout18);
            app.TPNOxMassCheckBox.ValueChangedFcn = createCallbackFcn(app, @TPNOxMassCheckBoxValueChanged, true);
            app.TPNOxMassCheckBox.Text = 'TP NOx Mass';
            app.TPCO2MassCheckBox = uicheckbox(app.GridLayout18);
            app.TPCO2MassCheckBox.ValueChangedFcn = createCallbackFcn(app, @TPCO2MassCheckBoxValueChanged, true);
            app.TPCO2MassCheckBox.Text = 'TP CO2 Mass';
            app.ClearallButton = uibutton(app.GridLayout18, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Icon = 'clear.png';
            app.ClearallButton.Tooltip = {'Unselect all the output plots'};
            app.ClearallButton.Text = 'Clear all';
            app.ControlPanel = uipanel(app.GridLayout16);
            app.ControlPanel.Title = '   Control';
            app.GridLayout17 = uigridlayout(app.ControlPanel);
            app.SimulationLamp = uilamp(app.GridLayout17);
            app.StatusLabel = uilabel(app.GridLayout17);
            app.StatusLabel.Text = '';
            app.StartSimulationButton = uibutton(app.GridLayout17, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Icon = 'greenarrowicon.gif';
            app.StartSimulationButton.Enable = 'off';
            app.StartSimulationButton.Text = 'Start Simulation';
            app.GenerateReportButton = uibutton(app.GridLayout17, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Icon = 'pageicon.gif';
            app.GenerateReportButton.Enable = 'off';
            app.GenerateReportButton.Text = 'Generate Report';
            app.SessionLogTab = uitab(app.TabGroup);
            app.SessionLogTab.Title = 'Session Log';
            app.GridLayout40 = uigridlayout(app.SessionLogTab);
            app.SessiondetailsPanel = uipanel(app.GridLayout40);
            app.SessiondetailsPanel.Title = 'Session details';
            app.GridLayout43 = uigridlayout(app.SessiondetailsPanel);
            app.TimeLogLabel = uilabel(app.GridLayout43);
            app.TimeLogLabel.Text = 'Label3';
            app.SessionhistoryPanel = uipanel(app.GridLayout40);
            app.SessionhistoryPanel.Title = 'Session history';
            app.GridLayout42 = uigridlayout(app.SessionhistoryPanel);
            app.LogTextArea = uitextarea(app.GridLayout42);
            app.LogTextArea.Editable = 'off';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.GridLayout48 = uigridlayout(app.ReportTab);
            app.ReportAxes = uiaxes(app.GridLayout48);
            xlabel(app.ReportAxes, 'Time (s)')
            app.AxesrequiredtogeneratethereportLabel = uilabel(app.GridLayout48);
            app.AxesrequiredtogeneratethereportLabel.Text = 'Axes required to generate the report:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HEVApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end